classdef HOT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HOTToolboxUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        HOTLabel                      matlab.ui.control.Label
        HRFOPTIMIZATIONTOOLBOXLabel   matlab.ui.control.Label
        LoadCNTEEGButton              matlab.ui.control.Button
        DataextractionDropDown        matlab.ui.control.DropDown
        DataextractionDropDownLabel   matlab.ui.control.Label
        P6EditField                   matlab.ui.control.NumericEditField
        P6EditFieldLabel              matlab.ui.control.Label
        P5EditField                   matlab.ui.control.NumericEditField
        P5EditFieldLabel              matlab.ui.control.Label
        P4EditField                   matlab.ui.control.NumericEditField
        P4EditFieldLabel              matlab.ui.control.Label
        P3EditField                   matlab.ui.control.NumericEditField
        P3EditFieldLabel              matlab.ui.control.Label
        P2EditField                   matlab.ui.control.NumericEditField
        P2EditFieldLabel              matlab.ui.control.Label
        P1EditField                   matlab.ui.control.NumericEditField
        P1EditFieldLabel              matlab.ui.control.Label
        LoadSPMButton                 matlab.ui.control.Button
        HRFDropDown                   matlab.ui.control.DropDown
        HRFDropDownLabel              matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        ZEditField                    matlab.ui.control.EditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.EditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        XEditFieldLabel               matlab.ui.control.Label
        HemodynamicResponseFunctionPanel  matlab.ui.container.Panel
        ExportparametersButton        matlab.ui.control.Button
        StimulifunctionDropDown       matlab.ui.control.DropDown
        StimulifunctionDropDownLabel  matlab.ui.control.Label
        P7EditFieldLabel              matlab.ui.control.Label
        P7EditField                   matlab.ui.control.NumericEditField
        DefaultButton                 matlab.ui.control.Button
        OPTIMIZEButton                matlab.ui.control.Button
        BOLDsignalPanel               matlab.ui.container.Panel
        ClusternrEditField            matlab.ui.control.EditField
        ClusternrEditFieldLabel       matlab.ui.control.Label
        VoxelcoordinatesvxLabel       matlab.ui.control.Label
        ExtractButton                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        EEG2SPMButton                 matlab.ui.control.Button
        BETAEditField3                matlab.ui.control.EditField
        BETAEditField_8Label          matlab.ui.control.Label
        MSEEditField3                 matlab.ui.control.EditField
        MSEEditField_4Label           matlab.ui.control.Label
        BETAEditField2                matlab.ui.control.EditField
        BETAEditField_6Label          matlab.ui.control.Label
        MSEEditField2                 matlab.ui.control.EditField
        MSEEditField_3Label           matlab.ui.control.Label
        MSEEditField                  matlab.ui.control.EditField
        MSEEditFieldLabel             matlab.ui.control.Label
        BETAEditField                 matlab.ui.control.EditField
        BETAEditFieldLabel            matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        betas
        betas_can
        betas_gam
        betas_glo
        X
        Y
        Y1
        SPM
        e
        e_can
        e_gam
        e_glo
        KWY
        KWX
        KWX_can
        KWX_gam
        KWX_glo
        time
        YROI
        EEG
        eeglgth
        P_can
        P_gam
        P_glo

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';


            % default parameters initialization

            app.P_can=[6 16 1 1 6 0 32];
            app.P_gam=[8.6 0.547];
            app.P_glo=[6 12 0.9 0.9 0.35];
        end

        % Button pushed function: LoadSPMButton
        function LoadSPMButtonPushed(app, event)

            % Loads the SPM file and functional data,
            % in case of path discrepancies, allows you to update them

            [P, ~] = spm_select(1,'^SPM\.mat$','Select SPM.mat');
            if ~isempty(P)
                load(P);

                if strcmpi('i.i.d',SPM.xVi.form) && size(SPM.nscan,2)==1

                    app.SPM             = SPM;

                    if exist(app.SPM.xY.VY(1).fname,'file')
                        app.Y1=spm_data_read(app.SPM.xY.VY);
                        msgbox('The data has been loaded','INFO');
                    else
                        oldpath = app.SPM.xY.VY(1).fname;
                        lastSlashIdx = strfind(oldpath, '\');
                        lastSlashIdx = lastSlashIdx(end);
                        oldpath= oldpath(1:lastSlashIdx-1);
                        clear lastSlashIdx
                        uiwait(errordlg('Functional data directory is missing. Please select correct directory of your fMRI data','BEWARE!'));
                        newpath = uigetdir('*.*');

                        for i=1:length(app.SPM.xY.VY)
                            app.SPM.xY.VY(i).fname = strrep(app.SPM.xY.VY(i).fname,oldpath,newpath);
                        end

                        app.SPM.swd=strrep(P, '\SPM.mat', '');
                        cd(app.SPM.swd);
                        SPM=app.SPM;
                        save('SPM.mat','SPM')
                        app.Y1=spm_data_read(app.SPM.xY.VY);
                        figure(app.HOTToolboxUIFigure);
                        msgbox('The data has been updated and loaded','INFO');
                    end
                else
                    errordlg('Please Load SPM.mat file created with one session and serial correlation set to -none-','BEWARE!')
                end

            end

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            %Calculates GLM for current settings and displays the results

            if isprop(app, 'SPM') && ~isempty(app.SPM)

                hold(app.UIAxes,'off');
                hold(app.UIAxes2,'off');
                hold(app.UIAxes3,'off');
                app.time=linspace(0,app.SPM.nscan*app.SPM.xY.RT,app.SPM.nscan)';

                %VOXEL LEVEL
                if strcmpi('Voxel-level',app.DataextractionDropDown.Value)

                    if ~isempty(app.XEditField.Value) && ~isempty(app.YEditField.Value) && ~isempty(app.ZEditField.Value)
                        app.Y=zeros(app.SPM.nscan,1);
                        
                        Xcord=app.XEditField.Value;
                        Ycord=app.YEditField.Value;
                        Zcord=app.ZEditField.Value;
                        dimX=app.SPM.VM.dim(1);
                        dimY=app.SPM.VM.dim(2);
                        dimZ=app.SPM.VM.dim(3);

                        if str2double(Xcord)<1 || str2double(Xcord)>dimX || str2double(Ycord)<1 || str2double(Ycord)>dimY || str2double(Zcord)<1 || str2double(Zcord)>dimZ
                            errordlg('Coordinates cannot exceed the image size','BEWARE!');
                            return
                        end

                        for i=1:(app.SPM.nscan)
                            app.Y(i,1)=app.Y1(str2double(Xcord),str2double(Ycord),str2double(Zcord),i);
                        end
                        
                        if mean(app.Y) == 0
                            errordlg('You have chosen voxel out of brain mask','BEWARE!');
                            return
                        end

                        if strcmpi('SPMReg',app.StimulifunctionDropDown.Value)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical(app.Y,app.SPM,app.P_can);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma(app.Y,app.SPM,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover(app.Y,app.SPM,app.P_glo);

                        elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        end

                        % plotting canonical HRF results
                        plot(app.UIAxes,app.time,app.KWY-app.betas_can(end),'--')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.time,app.KWX_can(:,1)*app.betas_can(1),'LineWidth',2)
                        yMin = 1.2*min(app.KWY-app.betas_can(end));
                        yMax = 1.2*max(app.KWY-app.betas_can(end));
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes,0.1)
                        end
                        app.UIAxes.YLim = [yMin, yMax];
                        hold(app.UIAxes,'off')

                        % plotting gamma HRF results
                        plot(app.UIAxes2,app.time,app.KWY-app.betas_gam(end),'--')
                        hold(app.UIAxes2,'on')
                        plot(app.UIAxes2,app.time,app.KWX_gam(:,1)*app.betas_gam(1),'LineWidth',2)
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes2,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes2,0.1)
                        end
                        app.UIAxes2.YLim = [yMin, yMax];
                        hold(app.UIAxes2,'off')

                        % plotting glover HRF results
                        plot(app.UIAxes3,app.time,app.KWY-app.betas_glo(end),'--')
                        hold(app.UIAxes3,'on')
                        plot(app.UIAxes3,app.time,app.KWX_glo(:,1)*app.betas_glo(1),'LineWidth',2)
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes3,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes3,0.1)
                        end
                        app.UIAxes3.YLim = [yMin, yMax];
                        hold(app.UIAxes3,'off')

                        % beta & MSE values print
                        bety_can=sprintf('%g', round(app.betas_can(1),2));
                        blad_can=sprintf('%g', round(app.e_can,2));
                        bety_gam=sprintf('%g', round(app.betas_gam(1),2));
                        blad_gam=sprintf('%g', round(app.e_gam,2));
                        bety_glo=sprintf('%g', round(app.betas_glo(1),2));
                        blad_glo=sprintf('%g', round(app.e_glo,2));
                        app.BETAEditField.Value=bety_can;
                        app.MSEEditField.Value=blad_can;
                        app.BETAEditField2.Value=bety_gam;
                        app.MSEEditField2.Value=blad_gam;
                        app.BETAEditField3.Value=bety_glo;
                        app.MSEEditField3.Value=blad_glo;

                    else
                        errordlg('Enter voxel coordinates 1st!','BEWARE!');
                    end


                    % CLUSTER LEVEL
                else
                    if isprop(app, 'YROI') && ~isempty(app.YROI)

                        if strcmpi('SPMReg',app.StimulifunctionDropDown.Value)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical(app.YROI{1,1},app.SPM,app.P_can);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma(app.YROI{1,1},app.SPM,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover(app.YROI{1,1},app.SPM,app.P_glo);


                        elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_can,app.EEG.data.hdr.Fs);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_gam,app.EEG.data.hdr.Fs);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_glo,app.EEG.data.hdr.Fs);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end

                        end

                        % plotting canonical HRF results
                        plot(app.UIAxes,app.time,app.KWY-app.betas_can(end),'--')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.time,app.KWX_can(:,1)*app.betas_can(1),'LineWidth',2)
                        yMin = 1.2*min(app.KWY-app.betas_can(end));
                        yMax = 1.2*max(app.KWY-app.betas_can(end));
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes,0.1)
                        end     
                        app.UIAxes.YLim = [yMin, yMax];
                        hold(app.UIAxes,'off')

                        % plotting gamma HRF results
                        plot(app.UIAxes2,app.time,app.KWY-app.betas_gam(end),'--')
                        hold(app.UIAxes2,'on')
                        plot(app.UIAxes2,app.time,app.KWX_gam(:,1)*app.betas_gam(1),'LineWidth',2)
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes2,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes2,0.1)
                        end
                        app.UIAxes2.YLim = [yMin, yMax];
                        hold(app.UIAxes2,'off')

                        % plotting glover HRF results
                        plot(app.UIAxes3,app.time,app.KWY-app.betas_glo(end),'--')
                        hold(app.UIAxes3,'on')
                        plot(app.UIAxes3,app.time,app.KWX_glo(:,1)*app.betas_glo(1),'LineWidth',2)
                        for i=1:length(app.SPM.Sess.U(1).ons)
                            xpatch=[ceil(app.SPM.Sess.U(1).ons(i)),ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))+32,ceil(app.SPM.Sess.U(1).ons(i))];
                            ypatch=[yMin,yMin,yMax,yMax];
                            patch(app.UIAxes3,xpatch,ypatch,'r','EdgeColor','none')
                            alpha(app.UIAxes3,0.1)
                        end
                        app.UIAxes3.YLim = [yMin, yMax];
                        hold(app.UIAxes3,'off')

                        % beta & MSE values print
                        bety_can=sprintf('%g', round(app.betas_can(1),2));
                        blad_can=sprintf('%g', round(app.e_can,2));
                        bety_gam=sprintf('%g', round(app.betas_gam(1),2));
                        blad_gam=sprintf('%g', round(app.e_gam,2));
                        bety_glo=sprintf('%g', round(app.betas_glo(1),2));
                        blad_glo=sprintf('%g', round(app.e_glo,2));
                        app.BETAEditField.Value=bety_can;
                        app.MSEEditField.Value=blad_can;
                        app.BETAEditField2.Value=bety_gam;
                        app.MSEEditField2.Value=blad_gam;
                        app.BETAEditField3.Value=bety_glo;
                        app.MSEEditField3.Value=blad_glo;
                    else
                        errordlg('NO ROI data extracted','BEWARE!');
                    end
                end

            else
                errordlg('Load SPM.mat 1st.','BEWARE!');
            end
        end

        % Value changed function: HRFDropDown
        function HRFDropDownValueChanged(app, event)

            % HRF model change handling function

            value = app.HRFDropDown.Value;

            if strcmpi('Canonical',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='on';
                app.P4EditField.Visible='on';
                app.P5EditField.Visible='on';
                app.P6EditField.Visible='on';
                app.P7EditField.Visible='on';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='on';
                app.P4EditFieldLabel.Visible='on';
                app.P5EditFieldLabel.Visible='on';
                app.P6EditFieldLabel.Visible='on';
                app.P7EditFieldLabel.Visible='on';
                app.P1EditField.Value=app.P_can(1);
                app.P2EditField.Value=app.P_can(2);
                app.P3EditField.Value=app.P_can(3);
                app.P4EditField.Value=app.P_can(4);
                app.P5EditField.Value=app.P_can(5);
                app.P6EditField.Value=app.P_can(6);
                app.P7EditField.Value=app.P_can(7);

            elseif strcmpi('Gamma',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='off';
                app.P4EditField.Visible='off';
                app.P5EditField.Visible='off';
                app.P6EditField.Visible='off';
                app.P7EditField.Visible='off';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='off';
                app.P4EditFieldLabel.Visible='off';
                app.P5EditFieldLabel.Visible='off';
                app.P6EditFieldLabel.Visible='off';
                app.P7EditFieldLabel.Visible='off';
                app.P1EditField.Value=app.P_gam(1);
                app.P2EditField.Value=app.P_gam(2);
                app.P3EditField.Value=0;
                app.P4EditField.Value=0;
                app.P5EditField.Value=0;
                app.P6EditField.Value=0;
                app.P7EditField.Value=0;

            elseif strcmpi('Glover',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='on';
                app.P4EditField.Visible='on';
                app.P5EditField.Visible='on';
                app.P6EditField.Visible='off';
                app.P7EditField.Visible='off';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='on';
                app.P4EditFieldLabel.Visible='on';
                app.P5EditFieldLabel.Visible='on';
                app.P6EditFieldLabel.Visible='off';
                app.P7EditFieldLabel.Visible='off';
                app.P1EditField.Value=app.P_glo(1);
                app.P2EditField.Value=app.P_glo(2);
                app.P3EditField.Value=app.P_glo(3);
                app.P4EditField.Value=app.P_glo(4);
                app.P5EditField.Value=app.P_glo(5);
                app.P6EditField.Value=0;
                app.P7EditField.Value=0;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            %Clearing all axes and results

            cla(app.UIAxes,'reset')
            title(app.UIAxes, 'Fitted BOLD response with canonical HRF')
            xlabel(app.UIAxes, 'time[s]')
            ylabel(app.UIAxes, 'BOLD [a.u.]')
            app.BETAEditField.Value='';
            app.MSEEditField.Value='';

            cla(app.UIAxes2,'reset')
            title(app.UIAxes2, 'Fitted BOLD response with gamma HRF')
            xlabel(app.UIAxes2, 'time[s]')
            ylabel(app.UIAxes2, 'BOLD [a.u.]')
            app.BETAEditField2.Value='';
            app.MSEEditField2.Value='';

            cla(app.UIAxes3,'reset')
            title(app.UIAxes3, 'Fitted BOLD response with glover HRF')
            xlabel(app.UIAxes3, 'time[s]')
            ylabel(app.UIAxes3, 'BOLD [a.u.]')
            app.BETAEditField3.Value='';
            app.MSEEditField3.Value='';
        end

        % Value changed function: DataextractionDropDown
        function DataextractionDropDownValueChanged(app, event)

            % Extraction level change handling function

            value = app.DataextractionDropDown.Value;
            if strcmpi('Voxel-level',value)
                app.VoxelcoordinatesvxLabel.Visible='on';
                app.XEditField.Visible='on';
                app.YEditField.Visible='on';
                app.ZEditField.Visible='on';
                app.ExtractButton.Visible='off';
                app.ClusternrEditField.Visible='off';
                app.ClusternrEditFieldLabel.Visible='off';
                app.XEditFieldLabel.Visible='on';
                app.YEditFieldLabel.Visible='on';
                app.ZEditFieldLabel.Visible='on';


            elseif strcmpi('ROI-level',value)
                app.VoxelcoordinatesvxLabel.Visible='off';
                app.XEditField.Visible='off';
                app.YEditField.Visible='off';
                app.ZEditField.Visible='off';
                app.ExtractButton.Visible='on';
                app.ClusternrEditField.Visible='on';
                app.ClusternrEditFieldLabel.Visible='on';
                app.XEditFieldLabel.Visible='off';
                app.YEditFieldLabel.Visible='off';
                app.ZEditFieldLabel.Visible='off';

            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)

            % Cluster data extraction

            if isprop(app, 'SPM') && ~isempty(app.SPM)

                listofrois=dir(fullfile(app.SPM.swd,'*cluster.nii'));

                if ~isempty(listofrois)
                    clear maskS maskD maskIdx
                   
                    maskS=spm_vol(fullfile(listofrois.folder,listofrois.name));
                    maskD=spm_read_vols(maskS);
                    nrklastra=app.ClusternrEditField.Value;
                    nrklastra=str2double(nrklastra);

                    if nrklastra<=max(maskD(:)) && nrklastra>=1

                    maskIdx=find(maskD==nrklastra);
                    sig=zeros(length(app.SPM.nscan),length(maskIdx));

                    for k = 1:app.SPM.nscan
                        [data1] = spm_read_vols(spm_vol(app.SPM.xY.VY(k)));
                        sig(k,:) =  data1(maskIdx);
                    end

                    app.YROI{1,1}=mean(sig,2,"omitnan");
                    app.YROI{1,2}=strcat('Cluster nr:',num2str(nrklastra));
                    msgbox('The data has been extracted','INFO');
                    else
                    errordlg('It seems that there is no such cluster','BEWARE!');
                    end

                else
                    errordlg('There is no _cluster.nii in your SPM files directory','BEWARE!');
                end

            else
                errordlg('Load SPM.mat 1st.','BEWARE!');
            end

        end

        % Button pushed function: LoadCNTEEGButton
        function LoadCNTEEGButtonPushed(app, event)

            % Loading EEG data - determining events,
            % trimming signals and calculation of GMF and envelopes

            if isprop(app, 'SPM') && ~isempty(app.SPM)

                if ~isempty(which('ft_defaults'))

                    ft_defaults
                    app.EEG=struct([]);

                    uiwait(msgbox('SELECT EEG data','Select data'));  %select eeg data
                    [name, folder] = uigetfile('*.*');
                    if name ~= 0
                        app.EEG(1).path= fullfile(folder, name);
                        clear name

                        %define config informations for ft processing, process the data
                        app.EEG(1).cfg           = [];
                        app.EEG(1).cfg.dataset    = app.EEG(1).path;
                        app.EEG(1).cfg.continuous = 'yes';
                        app.EEG(1).cfg.channel    = { 'FP1' 'FP2' 'F7' 'F8' 'T7' 'T8' 'C5' 'C6' 'P5' 'P6' };
                        app.EEG(1).data = ft_preprocessing(app.EEG(1).cfg);
                        app.eeglgth=app.SPM.xY.RT*app.SPM.nscan*app.EEG.data.hdr.Fs-1;

                        %get events
                        event=ft_read_event(app.EEG(1).path);
                        idx = ismember({event.type},'trigger');
                        event=event(idx);
                        event = struct2table(event); % convert the struct array to a table
                        app.EEG(1).event.trigger = event(event.value==0,2);
                        app.EEG(1).event.start = event(event.value==201,2);
                        app.EEG(1).event.end = event(event.value==202,2);
                        app.EEG(1).event.startdouble=(table2array(app.EEG(1).event.start)-app.EEG(1).event.trigger.sample(1))/app.EEG.data.hdr.Fs;
                        app.EEG(1).event.enddouble=(table2array(app.EEG(1).event.end)-app.EEG(1).event.trigger.sample(1))/app.EEG.data.hdr.Fs;
                        app.EEG(1).event.dur=app.EEG(1).event.enddouble-app.EEG(1).event.startdouble;

                        %trim the signals
                        for j=1:length(app.EEG(1).data.label)
                            app.EEG(1).CUT{1,j} = app.EEG(1).data.trial{1,1}(j,app.EEG(1).event.trigger.sample(1):app.EEG(1).event.trigger.sample(1)+app.eeglgth);
                        end

                        %gmf
                        app.EEG.GMF(1).data = ft_globalmeanfield(app.EEG(1).cfg, app.EEG(1).data);
                        app.EEG.GMF(1).cut = app.EEG.GMF(1).data.avg(1,app.EEG(1).event.trigger.sample(1):app.EEG(1).event.trigger.sample(1)+app.eeglgth);
                        app.EEG.GMF(1).cut=zscore(app.EEG.GMF(1).cut);
                        app.EEG.GMF(1).cut(1,1:5*app.EEG.data.fsample)=mean(app.EEG.GMF(1).cut);
                        app.EEG.GMF(1).cut(1,length(app.EEG.GMF(1).cut)-5*app.EEG.data.fsample:end)=mean(app.EEG.GMF(1).cut);

                        %peakL envelope
                        [YLenv,~] = envelope(app.EEG(1).CUT{1,3},300,'peak');
                        app.EEG.PeakL(1).cut=detrend(YLenv);
                        app.EEG.PeakL(1).cut=zscore(app.EEG.PeakL(1).cut);
                        app.EEG.PeakL(1).cut(1,1:5*app.EEG.data.fsample)=mean(app.EEG.PeakL(1).cut);
                        app.EEG.PeakL(1).cut(1,length(app.EEG(1).CUT{1,3})-5*app.EEG.data.fsample:end)=mean(app.EEG.PeakL(1).cut);
                        app.EEG.PeakL(1).cut=app.EEG.PeakL(1).cut ./ max(app.EEG.PeakL(1).cut);
                        app.EEG.PeakL(1).cut(app.EEG.PeakL(1).cut<0.1)=0;

                        %peakR envelope
                        [YRenv,~] = envelope(app.EEG(1).CUT{1,1},300,'peak');
                        app.EEG.PeakR(1).cut=detrend(YRenv);
                        app.EEG.PeakR(1).cut=zscore(app.EEG.PeakR(1).cut);
                        app.EEG.PeakR(1).cut(1,1:5*app.EEG.data.fsample)=mean(app.EEG.PeakR(1).cut);
                        app.EEG.PeakR(1).cut(1,length(app.EEG(1).CUT{1,1})-5*app.EEG.data.fsample:end)=mean(app.EEG.PeakR(1).cut);
                        app.EEG.PeakR(1).cut=app.EEG.PeakR(1).cut ./ max(app.EEG.PeakR(1).cut);
                        app.EEG.PeakR(1).cut(app.EEG.PeakR(1).cut<0.1)=0;

                        %RMSL envelope
                        [YLenvRMS,~] = envelope(app.EEG(1).CUT{1,3},500,'rms');
                        app.EEG.RMSL(1).cut=detrend(YLenvRMS);
                        app.EEG.RMSL(1).cut=zscore(app.EEG.RMSL(1).cut);
                        app.EEG.RMSL(1).cut(1,1:5*app.EEG.data.fsample)=mean(app.EEG.RMSL(1).cut);
                        app.EEG.RMSL(1).cut(1,length(app.EEG(1).CUT{1,3})-5*app.EEG.data.fsample:end)=mean(app.EEG.RMSL(1).cut);
                        app.EEG.RMSL(1).cut=app.EEG.RMSL(1).cut ./ max(app.EEG.RMSL(1).cut);
                        app.EEG.RMSL(1).cut(app.EEG.RMSL(1).cut<0.1)=0;

                        %RMSR envelope
                        [YRenvRMS,~] = envelope(app.EEG(1).CUT{1,1},500,'rms');
                        app.EEG.RMSR(1).cut=detrend(YRenvRMS);
                        app.EEG.RMSR(1).cut=zscore(app.EEG.RMSR(1).cut);
                        app.EEG.RMSR(1).cut(1,1:5*app.EEG.data.fsample)=mean(app.EEG.RMSR(1).cut);
                        app.EEG.RMSR(1).cut(1,length(app.EEG(1).CUT{1,1})-5*app.EEG.data.fsample:end)=mean(app.EEG.RMSR(1).cut);
                        app.EEG.RMSR(1).cut=app.EEG.RMSR(1).cut ./ max(app.EEG.RMSR(1).cut);
                        app.EEG.RMSR(1).cut(app.EEG.RMSR(1).cut<0.1)=0;

                        clc
                        figure(app.HOTToolboxUIFigure);
                        uiwait(msgbox('EEG data loaded succesfully','WELL DONE!'));
                    end

                else
                    errordlg('Make sure you have added the FieldTrip toolbox to your Matlab path','BEWARE!');
                end

            else
                errordlg('Load SPM.mat 1st.','BEWARE!');
            end
        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)

            % P1 change handling function

            value = app.P1EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(1)=value;
            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P_gam(1)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(1)=value;
            end

        end

        % Value changed function: P2EditField
        function P2EditFieldValueChanged(app, event)

            % P2 change handling function

            value = app.P2EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(2)=value;
            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P_gam(2)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(2)=value;
            end
        end

        % Value changed function: P3EditField
        function P3EditFieldValueChanged(app, event)

            % P3 change handling function

            value = app.P3EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(3)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(3)=value;
            end
        end

        % Value changed function: P4EditField
        function P4EditFieldValueChanged(app, event)

            % P4 change handling function

            value = app.P4EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(4)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(4)=value;
            end
        end

        % Value changed function: P5EditField
        function P5EditFieldValueChanged(app, event)

            % P5 change handling function

            value = app.P5EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(5)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(5)=value;
            end
        end

        % Value changed function: P6EditField
        function P6EditFieldValueChanged(app, event)

            % P6 change handling function

            value = app.P6EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(6)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(6)=value;
            end
        end

        % Value changed function: P7EditField
        function P7EditFieldValueChanged(app, event)

            % P7 change handling function

            value = app.P7EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(7)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(7)=value;
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)

            % Reset current HRF parameters to default values

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P1EditField.Value=6;
                app.P_can(1)=6;
                app.P2EditField.Value=16;
                app.P_can(2)=16;
                app.P3EditField.Value=1;
                app.P_can(3)=1;
                app.P4EditField.Value=1;
                app.P_can(4)=1;
                app.P5EditField.Value=6;
                app.P_can(5)=6;
                app.P6EditField.Value=0;
                app.P_can(6)=0;
                app.P7EditField.Value=32;
                app.P_can(7)=32;

            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P1EditField.Value=8.6;
                app.P_gam(1)=8.6;
                app.P2EditField.Value=0.54;
                app.P_gam(2)=0.54;

            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P1EditField.Value=6;
                app.P_glo(1)=6;
                app.P2EditField.Value=12;
                app.P_glo(2)=12;
                app.P3EditField.Value=0.9;
                app.P_glo(3)=0.9;
                app.P4EditField.Value=0.9;
                app.P_glo(4)=0.9;
                app.P5EditField.Value=0.35;
                app.P_glo(5)=0.35;
            end
        end

        % Button pushed function: OPTIMIZEButton
        function OPTIMIZEButtonPushed(app, event)

            % HRF optimization support - depending on BOLD signal
            % (voxel or cluster), model and stimuli function used

            if strcmpi('Voxel-level',app.DataextractionDropDown.Value)

                if isprop(app,'Y') && ~isempty(app.Y)
                    Yopti=app.Y;
                else
                    errordlg('You need to extract signal 1st','BEWARE!');
                    return
                end

            elseif strcmpi('ROI-level',app.DataextractionDropDown.Value)

                if isprop(app,'YROI') && ~isempty(app.YROI)
                    Yopti=app.YROI{1,1};
                else
                    errordlg('You need to extract signal 1st','BEWARE!');
                    return
                end

            end


            if exist('Yopti','var') == 1

                if strcmpi('Canonical',app.HRFDropDown.Value)

                    if strcmpi('SPMreg',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC(Yopti,app.SPM);
                    elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC_eeg(Yopti,app.SPM,app.EEG.GMF.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakR.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value)
                        Pcopt=canonicaloptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSR.cut,app.EEG.data.hdr.Fs);
                    end

                    app.P1EditField.Value=Pcopt(1);
                    app.P_can(1)=Pcopt(1);
                    app.P2EditField.Value=Pcopt(2);
                    app.P_can(2)=Pcopt(2);
                    app.P3EditField.Value=Pcopt(3);
                    app.P_can(3)=Pcopt(3);
                    app.P4EditField.Value=Pcopt(4);
                    app.P_can(4)=Pcopt(4);
                    app.P5EditField.Value=Pcopt(5);
                    app.P_can(5)=Pcopt(5);
                    app.P6EditField.Value=Pcopt(6);
                    app.P_can(6)=Pcopt(6);
                    app.P7EditField.Value=Pcopt(7);
                    app.P_can(7)=Pcopt(7);

                elseif strcmpi('Gamma',app.HRFDropDown.Value)

                    if strcmpi('SPMreg',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC(Yopti,app.SPM);
                    elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC_eeg(Yopti,app.SPM,app.EEG.GMF.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakR.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value)
                        Pgopt=gammaoptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSR.cut,app.EEG.data.hdr.Fs);
                    end

                    app.P1EditField.Value=Pgopt(1);
                    app.P_gam(1)=Pgopt(1);
                    app.P2EditField.Value=Pgopt(2);
                    app.P_gam(2)=Pgopt(2);

                elseif strcmpi('Glover',app.HRFDropDown.Value)

                    if strcmpi('SPMreg',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC(Yopti,app.SPM);
                    elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC_eeg(Yopti,app.SPM,app.EEG.GMF.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC_eeg(Yopti,app.SPM,app.EEG.PeakR.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSL.cut,app.EEG.data.hdr.Fs);
                    elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value)
                        Pglopt=gloveroptimizeFMC_eeg(Yopti,app.SPM,app.EEG.RMSR.cut,app.EEG.data.hdr.Fs);
                    end

                    app.P1EditField.Value=Pglopt(1);
                    app.P_glo(1)=Pglopt(1);
                    app.P2EditField.Value=Pglopt(2);
                    app.P_glo(2)=Pglopt(2);
                    app.P3EditField.Value=Pglopt(3);
                    app.P_glo(3)=Pglopt(3);
                    app.P4EditField.Value=Pglopt(4);
                    app.P_glo(4)=Pglopt(4);
                    app.P5EditField.Value=Pglopt(5);
                    app.P_glo(5)=Pglopt(5);
                end

            else
                errordlg('You need to extract signal 1st','BEWARE!');
            end

        end

        % Button pushed function: EEG2SPMButton
        function EEG2SPMButtonPushed(app, event)
            % moves to the EEG2SPM toolbox
            % which can create SPM.mat file for HOT toolbox.

            EEG2SPM
        end

        % Button pushed function: ExportparametersButton
        function ExportparametersButtonPushed(app, event)
            % exports HRF parameters to .mat file
            % which can be loaded into EEG2SPM

            parameters.can = app.P_can;
            parameters.gam = app.P_gam;
            parameters.glo = app.P_glo;

            % Choosing path
            [file, path] = uiputfile('parameters.mat', 'Save Parameters As');
            fullFilePath = fullfile(path, file);
            save(fullFilePath, 'parameters');
            figure(app.HOTToolboxUIFigure);
            uiwait(msgbox('HRF parameters saved succesfully','WELL DONE!'));

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HOTToolboxUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {723, 723};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HOTToolboxUIFigure = uifigure('Visible', 'off');
            app.HOTToolboxUIFigure.Name = 'HOT Toolbox';
            app.HOTToolboxUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HOTToolboxUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BOLDsignalPanel = uipanel(app.LeftPanel);
            app.BOLDsignalPanel.Title = 'BOLD signal';
            app.ExtractButton = uibutton(app.BOLDsignalPanel, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.VoxelcoordinatesvxLabel = uilabel(app.BOLDsignalPanel);
            app.VoxelcoordinatesvxLabel.Visible = 'off';
            app.VoxelcoordinatesvxLabel.Text = 'Voxel coordinates [vx]:';
            app.ClusternrEditFieldLabel = uilabel(app.BOLDsignalPanel);
            app.ClusternrEditFieldLabel.Text = 'Cluster nr';
            app.ClusternrEditField = uieditfield(app.BOLDsignalPanel, 'text');
            app.ClusternrEditField.CharacterLimits = [1 Inf];
            app.ClusternrEditField.InputType = 'digits';
            app.ClusternrEditField.Value = '1';
            app.HemodynamicResponseFunctionPanel = uipanel(app.LeftPanel);
            app.HemodynamicResponseFunctionPanel.Title = 'Hemodynamic Response Function';
            app.OPTIMIZEButton = uibutton(app.HemodynamicResponseFunctionPanel, 'push');
            app.OPTIMIZEButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZEButtonPushed, true);
            app.OPTIMIZEButton.Text = 'OPTIMIZE!';
            app.DefaultButton = uibutton(app.HemodynamicResponseFunctionPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.P7EditField = uieditfield(app.HemodynamicResponseFunctionPanel, 'numeric');
            app.P7EditField.ValueChangedFcn = createCallbackFcn(app, @P7EditFieldValueChanged, true);
            app.P7EditField.Value = 32;
            app.P7EditFieldLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.P7EditFieldLabel.Text = 'P7';
            app.StimulifunctionDropDownLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.StimulifunctionDropDownLabel.Text = 'Stimuli function';
            app.StimulifunctionDropDown = uidropdown(app.HemodynamicResponseFunctionPanel);
            app.StimulifunctionDropDown.Items = {'SPMReg', 'GMF', 'EnvPeakL', 'EnvPeakR', 'EnvRMSL', 'EnvRMSR'};
            app.StimulifunctionDropDown.Value = 'SPMReg';
            app.ExportparametersButton = uibutton(app.HemodynamicResponseFunctionPanel, 'push');
            app.ExportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportparametersButtonPushed, true);
            app.ExportparametersButton.Text = 'Export parameters';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'text');
            app.XEditField.CharacterLimits = [1 Inf];
            app.XEditField.InputType = 'digits';
            app.XEditField.Visible = 'off';
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Visible = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'text');
            app.YEditField.CharacterLimits = [1 Inf];
            app.YEditField.InputType = 'digits';
            app.YEditField.Visible = 'off';
            app.ZEditFieldLabel = uilabel(app.LeftPanel);
            app.ZEditFieldLabel.Visible = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.LeftPanel, 'text');
            app.ZEditField.CharacterLimits = [1 Inf];
            app.ZEditField.InputType = 'digits';
            app.ZEditField.Visible = 'off';
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HRFDropDownLabel = uilabel(app.LeftPanel);
            app.HRFDropDownLabel.Text = 'HRF';
            app.HRFDropDown = uidropdown(app.LeftPanel);
            app.HRFDropDown.Items = {'Canonical', 'Gamma', 'Glover'};
            app.HRFDropDown.ValueChangedFcn = createCallbackFcn(app, @HRFDropDownValueChanged, true);
            app.HRFDropDown.Value = 'Canonical';
            app.LoadSPMButton = uibutton(app.LeftPanel, 'push');
            app.LoadSPMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSPMButtonPushed, true);
            app.LoadSPMButton.Text = 'Load SPM';
            app.P1EditFieldLabel = uilabel(app.LeftPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.P1EditField.Value = 6;
            app.P2EditFieldLabel = uilabel(app.LeftPanel);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P2EditField.ValueChangedFcn = createCallbackFcn(app, @P2EditFieldValueChanged, true);
            app.P2EditField.Value = 16;
            app.P3EditFieldLabel = uilabel(app.LeftPanel);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P3EditField.ValueChangedFcn = createCallbackFcn(app, @P3EditFieldValueChanged, true);
            app.P3EditField.Value = 1;
            app.P4EditFieldLabel = uilabel(app.LeftPanel);
            app.P4EditFieldLabel.Text = 'P4';
            app.P4EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P4EditField.ValueChangedFcn = createCallbackFcn(app, @P4EditFieldValueChanged, true);
            app.P4EditField.Value = 1;
            app.P5EditFieldLabel = uilabel(app.LeftPanel);
            app.P5EditFieldLabel.Text = 'P5';
            app.P5EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P5EditField.ValueChangedFcn = createCallbackFcn(app, @P5EditFieldValueChanged, true);
            app.P5EditField.Value = 6;
            app.P6EditFieldLabel = uilabel(app.LeftPanel);
            app.P6EditFieldLabel.Text = 'P6';
            app.P6EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P6EditField.ValueChangedFcn = createCallbackFcn(app, @P6EditFieldValueChanged, true);
            app.DataextractionDropDownLabel = uilabel(app.LeftPanel);
            app.DataextractionDropDownLabel.Text = 'Data extraction';
            app.DataextractionDropDown = uidropdown(app.LeftPanel);
            app.DataextractionDropDown.Items = {'ROI-level', 'Voxel-level'};
            app.DataextractionDropDown.ValueChangedFcn = createCallbackFcn(app, @DataextractionDropDownValueChanged, true);
            app.DataextractionDropDown.Value = 'ROI-level';
            app.LoadCNTEEGButton = uibutton(app.LeftPanel, 'push');
            app.LoadCNTEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCNTEEGButtonPushed, true);
            app.LoadCNTEEGButton.Text = 'Load CNT EEG';
            app.HRFOPTIMIZATIONTOOLBOXLabel = uilabel(app.LeftPanel);
            app.HRFOPTIMIZATIONTOOLBOXLabel.Text = 'HRF OPTIMIZATION TOOLBOX';
            app.HOTLabel = uilabel(app.LeftPanel);
            app.HOTLabel.Text = 'HOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Fitted BOLD response with glover HRF')
            xlabel(app.UIAxes3, 'time[s]')
            ylabel(app.UIAxes3, 'BOLD [a.u.]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Fitted BOLD response with gamma HRF')
            xlabel(app.UIAxes2, 'time[s]')
            ylabel(app.UIAxes2, 'BOLD [a.u.]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Fitted BOLD response with canonical HRF')
            xlabel(app.UIAxes, 'time[s]')
            ylabel(app.UIAxes, 'BOLD [a.u.]')
            zlabel(app.UIAxes, 'Z')
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BETAEditFieldLabel = uilabel(app.RightPanel);
            app.BETAEditFieldLabel.Text = 'BETA';
            app.BETAEditField = uieditfield(app.RightPanel, 'text');
            app.MSEEditFieldLabel = uilabel(app.RightPanel);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.RightPanel, 'text');
            app.MSEEditField_3Label = uilabel(app.RightPanel);
            app.MSEEditField_3Label.Text = 'MSE';
            app.MSEEditField2 = uieditfield(app.RightPanel, 'text');
            app.BETAEditField_6Label = uilabel(app.RightPanel);
            app.BETAEditField_6Label.Text = 'BETA';
            app.BETAEditField2 = uieditfield(app.RightPanel, 'text');
            app.MSEEditField_4Label = uilabel(app.RightPanel);
            app.MSEEditField_4Label.Text = 'MSE';
            app.MSEEditField3 = uieditfield(app.RightPanel, 'text');
            app.BETAEditField_8Label = uilabel(app.RightPanel);
            app.BETAEditField_8Label.Text = 'BETA';
            app.BETAEditField3 = uieditfield(app.RightPanel, 'text');
            app.EEG2SPMButton = uibutton(app.RightPanel, 'push');
            app.EEG2SPMButton.ButtonPushedFcn = createCallbackFcn(app, @EEG2SPMButtonPushed, true);
            app.EEG2SPMButton.Text = 'EEG2SPM';
            % Show the figure after all components are created
            app.HOTToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HOT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HOTToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HOTToolboxUIFigure)
        end
    end
end