classdef HRF_OPTIM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        HOTLabel                      matlab.ui.control.Label
        HRFOPTIMIZATIONTOOLBOXLabel   matlab.ui.control.Label
        ClusternrEditField            matlab.ui.control.EditField
        ClusternrEditFieldLabel       matlab.ui.control.Label
        LoadCNTEEGButton              matlab.ui.control.Button
        ClusterextractionButton       matlab.ui.control.Button
        DataextractionDropDown        matlab.ui.control.DropDown
        DataextractionDropDownLabel   matlab.ui.control.Label
        P6EditField                   matlab.ui.control.NumericEditField
        P6EditFieldLabel              matlab.ui.control.Label
        P5EditField                   matlab.ui.control.NumericEditField
        P5EditFieldLabel              matlab.ui.control.Label
        P4EditField                   matlab.ui.control.NumericEditField
        P4EditFieldLabel              matlab.ui.control.Label
        P3EditField                   matlab.ui.control.NumericEditField
        P3EditFieldLabel              matlab.ui.control.Label
        P2EditField                   matlab.ui.control.NumericEditField
        P2EditFieldLabel              matlab.ui.control.Label
        P1EditField                   matlab.ui.control.NumericEditField
        P1EditFieldLabel              matlab.ui.control.Label
        LoadSPMButton                 matlab.ui.control.Button
        HRFDropDown                   matlab.ui.control.DropDown
        HRFDropDownLabel              matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        ZEditField                    matlab.ui.control.EditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.EditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        XEditFieldLabel               matlab.ui.control.Label
        VoxelcoordinatesLabel         matlab.ui.control.Label
        StimulifunctionDropDown       matlab.ui.control.DropDown
        StimulifunctionDropDownLabel  matlab.ui.control.Label
        HemodynamicResponseFunctionPanel  matlab.ui.container.Panel
        P9EditField                   matlab.ui.control.NumericEditField
        P9EditFieldLabel              matlab.ui.control.Label
        P8EditField                   matlab.ui.control.NumericEditField
        P8EditFieldLabel              matlab.ui.control.Label
        P7EditField                   matlab.ui.control.NumericEditField
        P7EditFieldLabel              matlab.ui.control.Label
        DefaultButton                 matlab.ui.control.Button
        OptimizationfunctionDropDown  matlab.ui.control.DropDown
        OptimizationfunctionDropDownLabel  matlab.ui.control.Label
        OPTIMIZEButton                matlab.ui.control.Button
        BOLDsignalPanel               matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        BETAEditField_4               matlab.ui.control.EditField
        BETAEditField_8Label          matlab.ui.control.Label
        MSEEditField_4                matlab.ui.control.EditField
        MSEEditField_4Label           matlab.ui.control.Label
        BETAEditField_3               matlab.ui.control.EditField
        BETAEditField_6Label          matlab.ui.control.Label
        MSEEditField_3                matlab.ui.control.EditField
        MSEEditField_3Label           matlab.ui.control.Label
        BETAEditField_2               matlab.ui.control.EditField
        BETAEditField_4Label          matlab.ui.control.Label
        MSEEditField_2                matlab.ui.control.EditField
        MSEEditField_2Label           matlab.ui.control.Label
        MSEEditField                  matlab.ui.control.EditField
        MSEEditFieldLabel             matlab.ui.control.Label
        BETAEditField                 matlab.ui.control.EditField
        BETAEditFieldLabel            matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        betas
        betas_can
        betas_buxbal
        betas_gam
        betas_glo
        X
        Y
        Y1
        SPM
        e
        e_can
        e_buxbal
        e_gam
        e_glo
        KWY
        KWX
        KWX_can
        KWX_buxbal
        KWX_gam
        KWX_glo
        time
        YROI
        EEG
        eeglgth
        P_can
        P_buxbal
        P_gam
        P_glo

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.P_can=[6 16 1 1 6 0 32];
            app.P_buxbal=[3 5 6 6 2.5 0.38 2 10 5];
            app.P_gam=[8.6 0.547];
            app.P_glo=[6 12 0.9 0.9 0.35 8 9];
        end

        % Button pushed function: LoadSPMButton
        function LoadSPMButtonPushed(app, event)
            [P, ~] = spm_select(1,'^SPM\.mat$','Select SPM.mat');
            if ~isempty(P)
                load(P);
                app.SPM             = SPM;
                app.Y1=spm_data_read(app.SPM.xY.VY);

                msgbox('The data has been loaded','INFO');
            end


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            global ModelSet
            if isprop(app, 'SPM') && ~isempty(app.SPM)

                hold(app.UIAxes,'off');
                app.time=linspace(0,app.SPM.nscan*app.SPM.xY.RT,app.SPM.nscan)';

                if strcmpi('Voxel-level',app.DataextractionDropDown.Value)
                    app.Y=zeros(app.SPM.nscan,1);
                    for i=1:(app.SPM.nscan)
                        %Y(1,i)=app.Y1(37,94,38,i);
                        Xcord=app.XEditField.Value;
                        Ycord=app.YEditField.Value;
                        Zcord=app.ZEditField.Value;
                        app.Y(i,1)=app.Y1(str2double(Xcord),str2double(Ycord),str2double(Zcord),i);
                    end

                    if strcmpi('SPMReg',app.StimulifunctionDropDown.Value)
                        [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical(app.Y,app.SPM,app.P_can);


                        ModelSet.dw=2;     %scanner repetition time
                        ModelSet.delT=0.2;  %simulation time resolution
                        ModelSet.up=round(ModelSet.dw/ModelSet.delT); %up sampling ratio
                        %excitation
                        % ModelSet.lam(1)=10;  %phase of the stimulation box-car function in [s] %
                        %neuronal model
                        ModelSet.lam(2)=app.P_buxbal(1);   %Inhibitory fedback gain
                        ModelSet.lam(3)=app.P_buxbal(2);   %inhibitory feedback time constant
                        %hemodynamic model
                        ModelSet.lam(4)=app.P_buxbal(3);   %CBF delay
                        ModelSet.lam(5)=app.P_buxbal(4);   %CMRO2 delay
                        ModelSet.lam(6)=app.P_buxbal(5);   %flow metabolism relation
                        ModelSet.lam(7)=app.P_buxbal(6); %flow volume relation
                        ModelSet.lam(8)=app.P_buxbal(7);   %ballon transit time
                        ModelSet.lam(9)=app.P_buxbal(8);   %viscoelastic time constant
                        ModelSet.lam(10)=app.P_buxbal(9); %baseline blood volume
                      
                        [app.betas_buxbal,app.e_buxbal,app.KWY,app.KWX_buxbal]=opt_bballoon(app.Y,app.SPM,app.P_buxbal);
                        [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma(app.Y,app.SPM,app.P_gam);
                        [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover(app.Y,app.SPM,app.P_glo);


                    elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_balloon_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.GMF.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_balloon_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.PeakL.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_balloon_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.PeakR.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_balloon_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.RMSL.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value) && strcmpi('Canonical',app.HRFDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_balloon_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.Y,app.SPM,app.EEG.RMSR.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end

                    end

                    plot(app.UIAxes,app.time,app.KWY-app.betas_can(8),'--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.time,app.KWX_can(:,1)*app.betas_can(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes,0.1)
                    end
                    hold(app.UIAxes,'off')

                    plot(app.UIAxes_2,app.time,app.KWY-app.betas_buxbal(8),'--')
                    hold(app.UIAxes_2,'on')
                    plot(app.UIAxes_2,app.time,app.KWX_buxbal(:,1)*app.betas_buxbal(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_2,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_2,0.1)
                    end
                    hold(app.UIAxes_2,'off')

                    plot(app.UIAxes_3,app.time,app.KWY-app.betas_gam(8),'--')
                    hold(app.UIAxes_3,'on')
                    plot(app.UIAxes_3,app.time,app.KWX_gam(:,1)*app.betas_gam(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_3,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_3,0.1)
                    end
                    hold(app.UIAxes_3,'off')

                    plot(app.UIAxes_4,app.time,app.KWY-app.betas_glo(8),'--')
                    hold(app.UIAxes_4,'on')
                    plot(app.UIAxes_4,app.time,app.KWX_glo(:,1)*app.betas_glo(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_4,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_4,0.1)
                    end
                    hold(app.UIAxes_4,'off')

                    bety_can=sprintf('%g', round(app.betas_can(1),2));
                    blad_can=sprintf('%g', round(app.e_can,2));
                    bety_buxbal=sprintf('%g', round(app.betas_buxbal(1),2));
                    blad_buxbal=sprintf('%g', round(app.e_buxbal,2));
                    bety_gam=sprintf('%g', round(app.betas_gam(1),2));
                    blad_gam=sprintf('%g', round(app.e_gam,2));
                    bety_glo=sprintf('%g', round(app.betas_glo(1),2));
                    blad_glo=sprintf('%g', round(app.e_glo,2));
                    app.BETAEditField.Value=bety_can;
                    app.MSEEditField.Value=blad_can;
                    app.BETAEditField_2.Value=bety_buxbal;
                    app.MSEEditField_2.Value=blad_buxbal;
                    app.BETAEditField_3.Value=bety_gam;
                    app.MSEEditField_3.Value=blad_gam;
                    app.BETAEditField_4.Value=bety_glo;
                    app.MSEEditField_4.Value=blad_glo;

                else
                    if isprop(app, 'YROI') && ~isempty(app.YROI)

                        if strcmpi('SPMReg',app.StimulifunctionDropDown.Value)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical(app.YROI{1,1},app.SPM,app.P_can);

                              ModelSet.dw=2;     %scanner repetition time
                        ModelSet.delT=0.2;  %simulation time resolution
                        ModelSet.up=round(ModelSet.dw/ModelSet.delT); %up sampling ratio
                        %excitation
                        % ModelSet.lam(1)=10;  %phase of the stimulation box-car function in [s] %
                        %neuronal model
                        ModelSet.lam(2)=app.P_buxbal(1);   %Inhibitory fedback gain
                        ModelSet.lam(3)=app.P_buxbal(2);   %inhibitory feedback time constant
                        %hemodynamic model
                        ModelSet.lam(4)=app.P_buxbal(3);   %CBF delay
                        ModelSet.lam(5)=app.P_buxbal(4);   %CMRO2 delay
                        ModelSet.lam(6)=app.P_buxbal(5);   %flow metabolism relation
                        ModelSet.lam(7)=app.P_buxbal(6); %flow volume relation
                        ModelSet.lam(8)=app.P_buxbal(7);   %ballon transit time
                        ModelSet.lam(9)=app.P_buxbal(8);   %viscoelastic time constant
                        ModelSet.lam(10)=app.P_buxbal(9); %baseline blood volume

                        [app.betas_buxbal,app.e_buxbal,app.KWY,app.KWX_buxbal]=opt_bballoon(app.YROI{1,1},app.SPM,app.P_buxbal);
                        [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma(app.YROI{1,1},app.SPM,app.P_gam);
                        [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover(app.YROI{1,1},app.SPM,app.P_glo);


                        elseif strcmpi('GMF',app.StimulifunctionDropDown.Value)
                            if isprop(app, 'EEG') && ~isempty(app.EEG)
                                [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_can);
                                [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_bballoon_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_bal);
                                [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_gam);
                                [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.GMF.cut,app.P_glo);
                            else
                                errordlg('Load EEG.cnt 1st.','BEWARE!');
                            end


                        elseif strcmpi('EnvPeakL',app.StimulifunctionDropDown.Value)
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_bballoon_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakL.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvPeakR',app.StimulifunctionDropDown.Value) 
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_bballoon_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.PeakR.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvRMSL',app.StimulifunctionDropDown.Value) 
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_bballoon_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSL.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end


                    elseif strcmpi('EnvRMSR',app.StimulifunctionDropDown.Value) 
                        if isprop(app, 'EEG') && ~isempty(app.EEG)
                            [app.betas_can,app.e_can,app.KWY,app.KWX_can]=opt_canonical_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_can);
                            [app.betas_bal,app.e_bal,app.KWY,app.KWX_bal]=opt_bballoon_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_bal);
                            [app.betas_gam,app.e_gam,app.KWY,app.KWX_gam]=opt_gamma_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_gam);
                            [app.betas_glo,app.e_glo,app.KWY,app.KWX_glo]=opt_glover_eeg(app.YROI{1,1},app.SPM,app.EEG.RMSR.cut,app.P_glo);
                        else
                            errordlg('Load EEG.cnt 1st.','BEWARE!');
                        end

                    end

                                plot(app.UIAxes,app.time,app.KWY-app.betas_can(8),'--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.time,app.KWX_can(:,1)*app.betas_can(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes,0.1)
                    end
                    hold(app.UIAxes,'off')

                    plot(app.UIAxes_2,app.time,app.KWY-app.betas_buxbal(8),'--')
                    hold(app.UIAxes_2,'on')
                    plot(app.UIAxes_2,app.time,app.KWX_buxbal(:,1)*app.betas_buxbal(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_2,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_2,0.1)
                    end
                    hold(app.UIAxes_2,'off')

                    plot(app.UIAxes_3,app.time,app.KWY-app.betas_gam(8),'--')
                    hold(app.UIAxes_3,'on')
                    plot(app.UIAxes_3,app.time,app.KWX_gam(:,1)*app.betas_gam(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_3,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_3,0.1)
                    end
                    hold(app.UIAxes_3,'off')

                    plot(app.UIAxes_4,app.time,app.KWY-app.betas_glo(8),'--')
                    hold(app.UIAxes_4,'on')
                    plot(app.UIAxes_4,app.time,app.KWX_glo(:,1)*app.betas_glo(1),'LineWidth',2)
                    for i=1:length(app.SPM.Sess.U.ons)
                        xpatch=[ceil(app.SPM.Sess.U.ons(i)),ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))+32,ceil(app.SPM.Sess.U.ons(i))];
                        ypatch=[-5,-5,5,5];
                        patch(app.UIAxes_4,xpatch,ypatch,'r','EdgeColor','none')
                        alpha(app.UIAxes_4,0.1)
                    end
                    hold(app.UIAxes_4,'off')

                    bety_can=sprintf('%g', round(app.betas_can(1),2));
                    blad_can=sprintf('%g', round(app.e_can,2));
                    bety_buxbal=sprintf('%g', round(app.betas_buxbal(1),2));
                    blad_buxbal=sprintf('%g', round(app.e_buxbal,2));
                    bety_gam=sprintf('%g', round(app.betas_gam(1),2));
                    blad_gam=sprintf('%g', round(app.e_gam,2));
                    bety_glo=sprintf('%g', round(app.betas_glo(1),2));
                    blad_glo=sprintf('%g', round(app.e_glo,2));
                    app.BETAEditField.Value=bety_can;
                    app.MSEEditField.Value=blad_can;
                    app.BETAEditField_2.Value=bety_buxbal;
                    app.MSEEditField_2.Value=blad_buxbal;
                    app.BETAEditField_3.Value=bety_gam;
                    app.MSEEditField_3.Value=blad_gam;
                    app.BETAEditField_4.Value=bety_glo;
                    app.MSEEditField_4.Value=blad_glo;
                    else
                        errordlg('NO ROI data extracted','BEWARE!'); 
                    end
                end

            else
                errordlg('Load SPM.mat 1st.','BEWARE!');
            end
        end

        % Value changed function: HRFDropDown
        function HRFDropDownValueChanged(app, event)
            value = app.HRFDropDown.Value;
            if strcmpi('Canonical',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='on';
                app.P4EditField.Visible='on';
                app.P5EditField.Visible='on';
                app.P6EditField.Visible='on';
                app.P7EditField.Visible='on';
                app.P8EditField.Visible='off';
                app.P9EditField.Visible='off';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='on';
                app.P4EditFieldLabel.Visible='on';
                app.P5EditFieldLabel.Visible='on';
                app.P6EditFieldLabel.Visible='on';
                app.P7EditFieldLabel.Visible='on';
                app.P8EditFieldLabel.Visible='off';
                app.P9EditFieldLabel.Visible='off';
                app.P1EditField.Value=app.P_can(1);
                app.P2EditField.Value=app.P_can(2);
                app.P3EditField.Value=app.P_can(3);
                app.P4EditField.Value=app.P_can(4);
                app.P5EditField.Value=app.P_can(5);
                app.P6EditField.Value=app.P_can(6);
                app.P7EditField.Value=app.P_can(7);
                app.P8EditField.Value=0;
                app.P9EditField.Value=0;

            elseif strcmpi('BuxtonBalloon',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='on';
                app.P4EditField.Visible='on';
                app.P5EditField.Visible='on';
                app.P6EditField.Visible='on';
                app.P7EditField.Visible='on';
                app.P8EditField.Visible='on';
                app.P9EditField.Visible='on';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='on';
                app.P4EditFieldLabel.Visible='on';
                app.P5EditFieldLabel.Visible='on';
                app.P6EditFieldLabel.Visible='on';
                app.P7EditFieldLabel.Visible='on';
                app.P8EditFieldLabel.Visible='on';
                app.P9EditFieldLabel.Visible='on';
                app.P1EditField.Value=app.P_buxbal(1);
                app.P2EditField.Value=app.P_buxbal(2);
                app.P3EditField.Value=app.P_buxbal(3);
                app.P4EditField.Value=app.P_buxbal(4);
                app.P5EditField.Value=app.P_buxbal(5);
                app.P6EditField.Value=app.P_buxbal(6);
                app.P7EditField.Value=app.P_buxbal(7);
                app.P8EditField.Value=app.P_buxbal(8);
                app.P9EditField.Value=app.P_buxbal(9);

            elseif strcmpi('Gamma',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='off';
                app.P4EditField.Visible='off';
                app.P5EditField.Visible='off';
                app.P6EditField.Visible='off';
                app.P7EditField.Visible='off';
                app.P8EditField.Visible='off';
                app.P9EditField.Visible='off';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='off';
                app.P4EditFieldLabel.Visible='off';
                app.P5EditFieldLabel.Visible='off';
                app.P6EditFieldLabel.Visible='off';
                app.P7EditFieldLabel.Visible='off';
                app.P8EditFieldLabel.Visible='off';
                app.P9EditFieldLabel.Visible='off';
                app.P1EditField.Value=app.P_gam(1);
                app.P2EditField.Value=app.P_gam(2);
                app.P3EditField.Value=0;
                app.P4EditField.Value=0;
                app.P5EditField.Value=0;
                app.P6EditField.Value=0;
                app.P7EditField.Value=0;
                app.P8EditField.Value=0;
                app.P9EditField.Value=0;

            elseif strcmpi('Glover',value)
                app.P1EditField.Visible='on';
                app.P2EditField.Visible='on';
                app.P3EditField.Visible='on';
                app.P4EditField.Visible='on';
                app.P5EditField.Visible='on';
                app.P6EditField.Visible='on';
                app.P7EditField.Visible='on';
                app.P8EditField.Visible='off';
                app.P9EditField.Visible='off';
                app.P1EditFieldLabel.Visible='on';
                app.P2EditFieldLabel.Visible='on';
                app.P3EditFieldLabel.Visible='on';
                app.P4EditFieldLabel.Visible='on';
                app.P5EditFieldLabel.Visible='on';
                app.P6EditFieldLabel.Visible='on';
                app.P7EditFieldLabel.Visible='on';
                app.P8EditFieldLabel.Visible='off';
                app.P9EditFieldLabel.Visible='off';
                app.P1EditField.Value=app.P_glo(1);
                app.P2EditField.Value=app.P_glo(2);
                app.P3EditField.Value=app.P_glo(3);
                app.P4EditField.Value=app.P_glo(4);
                app.P5EditField.Value=app.P_glo(5);
                app.P6EditField.Value=app.P_glo(6);
                app.P7EditField.Value=app.P_glo(7);
                app.P8EditField.Value=0;
                app.P9EditField.Value=0;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes,'reset')
        end

        % Value changed function: DataextractionDropDown
        function DataextractionDropDownValueChanged(app, event)
            value = app.DataextractionDropDown.Value;
            if strcmpi('Voxel-level',value)
                app.VoxelcoordinatesLabel.Visible='on';
                app.XEditField.Visible='on';
                app.YEditField.Visible='on';
                app.ZEditField.Visible='on';
                app.ClusterextractionButton.Visible='off';
                app.ClusternrEditField.Visible='off';
                app.ClusternrEditFieldLabel.Visible='off';
                app.XEditFieldLabel.Visible='on';
                app.YEditFieldLabel.Visible='on';
                app.ZEditFieldLabel.Visible='on';


            elseif strcmpi('ROI-level',value)
                app.VoxelcoordinatesLabel.Visible='off';
                app.XEditField.Visible='off';
                app.YEditField.Visible='off';
                app.ZEditField.Visible='off';
                app.ClusterextractionButton.Visible='on';
                app.ClusternrEditField.Visible='on';
                app.ClusternrEditFieldLabel.Visible='on';
                app.XEditFieldLabel.Visible='off';
                app.YEditFieldLabel.Visible='off';
                app.ZEditFieldLabel.Visible='off';

            end
        end

        % Button pushed function: ClusterextractionButton
        function ClusterextractionButtonPushed(app, event)

       
            listofrois=dir(fullfile(app.SPM.swd,'*cluster.nii'));

                clear maskS maskD maskIdx
                maskS=spm_vol(fullfile(listofrois.folder,listofrois.name));
                maskD=spm_read_vols(maskS);
                nrklastra=app.ClusternrEditField.Value;
                nrklastra=str2double(nrklastra);

                    maskIdx=find(maskD==nrklastra);

                    sig=zeros(length(app.SPM.nscan),length(maskIdx));

                    for k = 1:app.SPM.nscan
                        [data1] = spm_read_vols(spm_vol(app.SPM.xY.VY(k)));
                        sig(k,:) =  data1(maskIdx);
                    end

                    app.YROI{1,1}=mean(sig,2);
                    app.YROI{1,2}=strcat('Cluster nr:',num2str(nrklastra))

        end

        % Button pushed function: LoadCNTEEGButton
        function LoadCNTEEGButtonPushed(app, event)
            if isprop(app, 'SPM') && ~isempty(app.SPM)

                addpath X:\Nikodem\matlabcodes\MATLAB\fieldtrip-20181128
                ft_defaults
                app.EEG=struct([]);
                app.eeglgth=app.SPM.xY.RT*app.SPM.nscan*1000-1;

                uiwait(msgbox('SELECT EEG data','Select data'));  %select eeg data
                [name, folder] = uigetfile('*.*');
                if name ~= 0
                    app.EEG(1).path= fullfile(folder, name);
                    clear name

                    %define config informations for ft processing, process the data
                    app.EEG(1).cfg           = [];
                    app.EEG(1).cfg.dataset    = app.EEG(1).path;
                    app.EEG(1).cfg.continuous = 'yes';
                    app.EEG(1).cfg.channel    = { 'FP1' 'FP2' 'F7' 'F8' 'T7' 'T8' 'C5' 'C6' 'P5' 'P6' };
                    app.EEG(1).data = ft_preprocessing(app.EEG(1).cfg);
                    %get events
                    event=ft_read_event(app.EEG(1).path);
                    idx = ismember({event.type},'trigger');
                    event=event(idx);
                    event = struct2table(event); % convert the struct array to a table
                    %event = sortrows(event, 'value'); % sort the table by 'value'
                    app.EEG(1).event.trigger = event(event.value==0,2);
                    app.EEG(1).event.start = event(event.value==201,2);
                    app.EEG(1).event.end = event(event.value==202,2);

                    app.EEG(1).event.startdouble=(table2array(app.EEG(1).event.start)-app.EEG(1).event.trigger.sample(1))/1000;
                    app.EEG(1).event.enddouble=(table2array(app.EEG(1).event.end)-app.EEG(1).event.trigger.sample(1))/1000;
                    app.EEG(1).event.dur=app.EEG(1).event.enddouble-app.EEG(1).event.startdouble;


                    for j=1:length(app.EEG(1).data.label)
                        app.EEG(1).CUT{1,j} = app.EEG(1).data.trial{1,1}(j,app.EEG(1).event.trigger.sample(1):app.EEG(1).event.trigger.sample(1)+app.eeglgth);
                    end

                    %gmf
                    app.EEG.GMF(1).data = ft_globalmeanfield(app.EEG(1).cfg, app.EEG(1).data);
                    app.EEG.GMF(1).cut = app.EEG.GMF(1).data.avg(1,app.EEG(1).event.trigger.sample(1):app.EEG(1).event.trigger.sample(1)+app.eeglgth);
                    app.EEG.GMF(1).cut=zscore(app.EEG.GMF(1).cut);
                    app.EEG.GMF(1).cut(1,1:5000)=mean(app.EEG.GMF(1).cut);
                    app.EEG.GMF(1).cut(1,length(app.EEG.GMF(1).cut)-5000:end)=mean(app.EEG.GMF(1).cut);

                    %envelope
                    [YLenv,~] = envelope(app.EEG(1).CUT{1,3},300,'peak');
                    app.EEG.PeakL(1).cut=detrend(YLenv);
                    app.EEG.PeakL(1).cut=zscore(app.EEG.PeakL(1).cut);
                    app.EEG.PeakL(1).cut(1,1:5000)=mean(app.EEG.PeakL(1).cut);
                    app.EEG.PeakL(1).cut(1,length(app.EEG(1).CUT{1,3})-5000:end)=mean(app.EEG.PeakL(1).cut);
                    app.EEG.PeakL(1).cut=app.EEG.PeakL(1).cut ./ max(app.EEG.PeakL(1).cut);
                    app.EEG.PeakL(1).cut(app.EEG.PeakL(1).cut<0.1)=0;

                    [YRenv,~] = envelope(app.EEG(1).CUT{1,1},300,'peak');
                    app.EEG.PeakR(1).cut=detrend(YRenv);
                    app.EEG.PeakR(1).cut=zscore(app.EEG.PeakR(1).cut);
                    app.EEG.PeakR(1).cut(1,1:5000)=mean(app.EEG.PeakR(1).cut);
                    app.EEG.PeakR(1).cut(1,length(app.EEG(1).CUT{1,1})-5000:end)=mean(app.EEG.PeakR(1).cut);
                    app.EEG.PeakR(1).cut=app.EEG.PeakR(1).cut ./ max(app.EEG.PeakR(1).cut);
                    app.EEG.PeakR(1).cut(app.EEG.PeakR(1).cut<0.1)=0;

                    [YLenvRMS,~] = envelope(app.EEG(1).CUT{1,3},500,'rms');
                    app.EEG.RMSL(1).cut=detrend(YLenvRMS);
                    app.EEG.RMSL(1).cut=zscore(app.EEG.RMSL(1).cut);
                    app.EEG.RMSL(1).cut(1,1:5000)=mean(app.EEG.RMSL(1).cut);
                    app.EEG.RMSL(1).cut(1,length(app.EEG(1).CUT{1,3})-5000:end)=mean(app.EEG.RMSL(1).cut);
                    app.EEG.RMSL(1).cut=app.EEG.RMSL(1).cut ./ max(app.EEG.RMSL(1).cut);
                    app.EEG.RMSL(1).cut(app.EEG.RMSL(1).cut<0.1)=0;

                    [YRenvRMS,~] = envelope(app.EEG(1).CUT{1,1},500,'rms');
                    app.EEG.RMSR(1).cut=detrend(YRenvRMS);
                    app.EEG.RMSR(1).cut=zscore(app.EEG.RMSR(1).cut);
                    app.EEG.RMSR(1).cut(1,1:5000)=mean(app.EEG.RMSR(1).cut);
                    app.EEG.RMSR(1).cut(1,length(app.EEG(1).CUT{1,1})-5000:end)=mean(app.EEG.RMSR(1).cut);
                    app.EEG.RMSR(1).cut=app.EEG.RMSR(1).cut ./ max(app.EEG.RMSR(1).cut);
                    app.EEG.RMSR(1).cut(app.EEG.RMSR(1).cut<0.1)=0;

                    uiwait(msgbox('EEG data loaded succesfully','WELL DONE!'));
                end
            else
                errordlg('Load SPM.mat 1st.','BEWARE!');
            end

            %                 selpath=uigetdir;
            %                 cd(selpath)
            %                 EEG=app.EEG;
            %                 save EEG.mat EEG;
            %                 uiwait(msgbox('SAVED','WELL DONE!'));
        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)
            value = app.P1EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(1)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(1)=value;
            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P_gam(1)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(1)=value;
            end

        end

        % Value changed function: P2EditField
        function P2EditFieldValueChanged(app, event)
            value = app.P2EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(2)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(2)=value;
            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P_gam(2)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(2)=value;
            end
        end

        % Value changed function: P3EditField
        function P3EditFieldValueChanged(app, event)
            value = app.P3EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(3)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(3)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(3)=value;
            end
        end

        % Value changed function: P4EditField
        function P4EditFieldValueChanged(app, event)
            value = app.P4EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(4)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(4)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(4)=value;
            end
        end

        % Value changed function: P5EditField
        function P5EditFieldValueChanged(app, event)
            value = app.P5EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(5)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(5)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(5)=value;
            end
        end

        % Value changed function: P6EditField
        function P6EditFieldValueChanged(app, event)
            value = app.P6EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(6)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(6)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(6)=value;
            end
        end

        % Value changed function: P7EditField
        function P7EditFieldValueChanged(app, event)
            value = app.P7EditField.Value;

            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P_can(7)=value;
            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P_buxbal(7)=value;
            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P_glo(7)=value;
            end
        end

        % Value changed function: P8EditField
        function P8EditFieldValueChanged(app, event)
            value = app.P8EditField.Value;
            app.P_buxbal(8)=value;
        end

        % Value changed function: P9EditField
        function P9EditFieldValueChanged(app, event)
            value = app.P9EditField.Value;
            app.P_buxbal(9)=value;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
      
value = app.P1EditField.Value;


            if strcmpi('Canonical',app.HRFDropDown.Value)
                app.P1EditField.Value=6;
                app.P_can(1)=6;
                app.P2EditField.Value=16;
                app.P_can(2)=16;
                app.P3EditField.Value=1;
                app.P_can(3)=1;
                app.P4EditField.Value=1;
                app.P_can(4)=1;
                app.P5EditField.Value=6;
                app.P_can(5)=6;
                app.P6EditField.Value=0;
                app.P_can(6)=0;
                app.P7EditField.Value=32;
                app.P_can(7)=32;

            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)
                app.P1EditField.Value=3;
                app.P_buxbal(1)=3;
                app.P2EditField.Value=5;
                app.P_buxbal(2)=5;
                app.P3EditField.Value=6;
                app.P_buxbal(3)=6;
                app.P4EditField.Value=6;
                app.P_buxbal(4)=6;
                app.P5EditField.Value=2.5;
                app.P_buxbal(5)=2.5;
                app.P6EditField.Value=0.38;
                app.P_buxbal(6)=0.38;
                app.P7EditField.Value=2;
                app.P_buxbal(7)=2;
                app.P8EditField.Value=10;
                app.P_buxbal(8)=10;
                app.P9EditField.Value=5;
                app.P_buxbal(9)=5;

            elseif strcmpi('Gamma',app.HRFDropDown.Value)
                app.P1EditField.Value=8.6;
                app.P_gam(1)=8.6;
                app.P2EditField.Value=0.54;
                app.P_gam(2)=0.54;


            elseif strcmpi('Glover',app.HRFDropDown.Value)
                app.P1EditField.Value=6;
                app.P_glo(1)=6;
                app.P2EditField.Value=12;
                app.P_glo(1)=12;
                app.P3EditField.Value=0.9;
                app.P_glo(1)=0.9;
                app.P4EditField.Value=0.9;
                app.P_glo(1)=0.9;
                app.P5EditField.Value=0.35;
                app.P_glo(1)=0.35;
                app.P6EditField.Value=8;
                app.P_glo(1)=8;
                app.P7EditField.Value=9;
                app.P_glo(1)=9;

            end
        end

        % Button pushed function: OPTIMIZEButton
        function OPTIMIZEButtonPushed(app, event)
            if strcmpi('Voxel-level',app.DataextractionDropDown.Value)
                Yopti=app.Y;
            elseif strcmpi('ROI-level',app.DataextractionDropDown.Value)
                Yopti=app.YROI{1,1};
            end

            if strcmpi('Canonical',app.HRFDropDown.Value)

                if strcmpi('fmincon',app.OptimizationfunctionDropDown.Value)
                    Pcopt=canonicaloptimizeFMC(Yopti,app.SPM);
                elseif strcmpi('fminsearchbnd',app.OptimizationfunctionDropDown.Value)
                    Pcopt=canonicaloptimizeFMSB(Yopti,app.SPM);
                end

                app.P1EditField.Value=Pcopt(1);
                app.P_can(1)=Pcopt(1);
                app.P2EditField.Value=Pcopt(2);
                app.P_can(2)=Pcopt(2);
                app.P3EditField.Value=Pcopt(3);
                app.P_can(3)=Pcopt(3);
                app.P4EditField.Value=Pcopt(4);
                app.P_can(4)=Pcopt(4);
                app.P5EditField.Value=Pcopt(5);
                app.P_can(5)=Pcopt(5);
                app.P6EditField.Value=Pcopt(6);
                app.P_can(6)=Pcopt(6);
                app.P7EditField.Value=Pcopt(7);
                app.P_can(7)=Pcopt(7);

            elseif strcmpi('BuxtonBalloon',app.HRFDropDown.Value)

                
                if strcmpi('fmincon',app.OptimizationfunctionDropDown.Value)
                    Pbopt=bballoonoptimizeFMC(Yopti,app.SPM);
                elseif strcmpi('fminsearchbnd',app.OptimizationfunctionDropDown.Value)
                    Pbopt=bballoonoptimizeFMSB(Yopti,app.SPM);
                end

                app.P1EditField.Value=Pbopt(1);
                app.P_buxbal(1)=Pbopt(1);
                app.P2EditField.Value=Pbopt(2);
                app.P_buxbal(2)=Pbopt(2);
                app.P3EditField.Value=Pbopt(3);
                app.P_buxbal(3)=Pbopt(3);
                app.P4EditField.Value=Pbopt(4);
                app.P_buxbal(4)=Pbopt(4);
                app.P5EditField.Value=Pbopt(5);
                app.P_buxbal(5)=Pbopt(5);
                app.P6EditField.Value=Pbopt(6);
                app.P_buxbal(6)=Pbopt(6);
                app.P7EditField.Value=Pbopt(7);
                app.P_buxbal(7)=Pbopt(7);
                app.P8EditField.Value=Pbopt(8);
                app.P_buxbal(8)=Pbopt(8);
                app.P9EditField.Value=Pbopt(9);
                app.P_buxbal(9)=Pbopt(9);

            elseif strcmpi('Gamma',app.HRFDropDown.Value)

                if strcmpi('fmincon',app.OptimizationfunctionDropDown.Value)
                    Pgopt=gammaoptimizeFMC(Yopti,app.SPM);
                elseif strcmpi('fminsearchbnd',app.OptimizationfunctionDropDown.Value)
                    Pgopt=gammaoptimizeFMSB(Yopti,app.SPM);
                end

                app.P1EditField.Value=Pgopt(1);
                app.P_gam(1)=Pgopt(1);
                app.P2EditField.Value=Pgopt(2);
                app.P_gam(2)=Pgopt(2);

            elseif strcmpi('Glover',app.HRFDropDown.Value)

                if strcmpi('fmincon',app.OptimizationfunctionDropDown.Value)
                    Pglopt=gloveroptimizeFMC(Yopti,app.SPM);
                elseif strcmpi('fminsearchbnd',app.OptimizationfunctionDropDown.Value)
                    Pglopt=gloveroptimizeFMSB(Yopti,app.SPM);
                end

                app.P1EditField.Value=Pglopt(1);
                app.P_glo(1)=Pglopt(1);
                app.P2EditField.Value=Pglopt(2);
                app.P_glo(2)=Pglopt(2);
                app.P3EditField.Value=Pglopt(3);
                app.P_glo(3)=Pglopt(3);
                app.P4EditField.Value=Pglopt(4);
                app.P_glo(4)=Pglopt(4);
                app.P5EditField.Value=Pglopt(5);
                app.P_glo(5)=Pglopt(5);
                app.P6EditField.Value=Pglopt(6);
                app.P_glo(6)=Pglopt(6);
                app.P7EditField.Value=Pglopt(7);
                app.P_glo(7)=Pglopt(7);
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {723, 723};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BOLDsignalPanel = uipanel(app.LeftPanel);
            app.BOLDsignalPanel.Title = 'BOLD signal';
            app.HemodynamicResponseFunctionPanel = uipanel(app.LeftPanel);
            app.HemodynamicResponseFunctionPanel.Title = 'Hemodynamic Response Function';
            app.OPTIMIZEButton = uibutton(app.HemodynamicResponseFunctionPanel, 'push');
            app.OPTIMIZEButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZEButtonPushed, true);
            app.OPTIMIZEButton.Text = 'OPTIMIZE!';
            app.OptimizationfunctionDropDownLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.OptimizationfunctionDropDownLabel.Text = 'Optimization function';
            app.OptimizationfunctionDropDown = uidropdown(app.HemodynamicResponseFunctionPanel);
            app.OptimizationfunctionDropDown.Items = {'fmincon', 'fminsearchbnd'};
            app.OptimizationfunctionDropDown.Value = 'fmincon';
            app.DefaultButton = uibutton(app.HemodynamicResponseFunctionPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.P7EditFieldLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.P7EditFieldLabel.Text = 'P7';
            app.P7EditField = uieditfield(app.HemodynamicResponseFunctionPanel, 'numeric');
            app.P7EditField.ValueChangedFcn = createCallbackFcn(app, @P7EditFieldValueChanged, true);
            app.P7EditField.Value = 32;
            app.P8EditFieldLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.P8EditFieldLabel.Text = 'P8';
            app.P8EditField = uieditfield(app.HemodynamicResponseFunctionPanel, 'numeric');
            app.P8EditField.ValueChangedFcn = createCallbackFcn(app, @P8EditFieldValueChanged, true);
            app.P8EditField.Value = 32;
            app.P9EditFieldLabel = uilabel(app.HemodynamicResponseFunctionPanel);
            app.P9EditFieldLabel.Text = 'P9';
            app.P9EditField = uieditfield(app.HemodynamicResponseFunctionPanel, 'numeric');
            app.P9EditField.ValueChangedFcn = createCallbackFcn(app, @P9EditFieldValueChanged, true);
            app.P9EditField.Value = 32;
            app.StimulifunctionDropDownLabel = uilabel(app.LeftPanel);
            app.StimulifunctionDropDownLabel.Text = 'Stimuli function';
            app.StimulifunctionDropDown = uidropdown(app.LeftPanel);
            app.StimulifunctionDropDown.Items = {'SPMReg', 'GMF', 'EnvPeakL', 'EnvPeakR', 'EnvRMSL', 'EnvRMSR'};
            app.StimulifunctionDropDown.Value = 'SPMReg';
            app.VoxelcoordinatesLabel = uilabel(app.LeftPanel);
            app.VoxelcoordinatesLabel.Text = 'Voxel coordinates:';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'text');
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'text');
            app.ZEditFieldLabel = uilabel(app.LeftPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.LeftPanel, 'text');
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HRFDropDownLabel = uilabel(app.LeftPanel);
            app.HRFDropDownLabel.Text = 'HRF';
            app.HRFDropDown = uidropdown(app.LeftPanel);
            app.HRFDropDown.Items = {'Canonical', 'BuxtonBalloon', 'Gamma', 'Glover'};
            app.HRFDropDown.ValueChangedFcn = createCallbackFcn(app, @HRFDropDownValueChanged, true);
            app.HRFDropDown.Value = 'Canonical';
            app.LoadSPMButton = uibutton(app.LeftPanel, 'push');
            app.LoadSPMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSPMButtonPushed, true);
            app.LoadSPMButton.Text = 'Load SPM';
            app.P1EditFieldLabel = uilabel(app.LeftPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.P1EditField.Value = 6;
            app.P2EditFieldLabel = uilabel(app.LeftPanel);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P2EditField.ValueChangedFcn = createCallbackFcn(app, @P2EditFieldValueChanged, true);
            app.P2EditField.Value = 16;
            app.P3EditFieldLabel = uilabel(app.LeftPanel);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P3EditField.ValueChangedFcn = createCallbackFcn(app, @P3EditFieldValueChanged, true);
            app.P3EditField.Value = 1;
            app.P4EditFieldLabel = uilabel(app.LeftPanel);
            app.P4EditFieldLabel.Text = 'P4';
            app.P4EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P4EditField.ValueChangedFcn = createCallbackFcn(app, @P4EditFieldValueChanged, true);
            app.P4EditField.Value = 1;
            app.P5EditFieldLabel = uilabel(app.LeftPanel);
            app.P5EditFieldLabel.Text = 'P5';
            app.P5EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P5EditField.ValueChangedFcn = createCallbackFcn(app, @P5EditFieldValueChanged, true);
            app.P5EditField.Value = 6;
            app.P6EditFieldLabel = uilabel(app.LeftPanel);
            app.P6EditFieldLabel.Text = 'P6';
            app.P6EditField = uieditfield(app.LeftPanel, 'numeric');
            app.P6EditField.ValueChangedFcn = createCallbackFcn(app, @P6EditFieldValueChanged, true);
            app.DataextractionDropDownLabel = uilabel(app.LeftPanel);
            app.DataextractionDropDownLabel.Text = 'Data extraction';
            app.DataextractionDropDown = uidropdown(app.LeftPanel);
            app.DataextractionDropDown.Items = {'Voxel-level', 'ROI-level'};
            app.DataextractionDropDown.ValueChangedFcn = createCallbackFcn(app, @DataextractionDropDownValueChanged, true);
            app.DataextractionDropDown.Value = 'Voxel-level';
            app.ClusterextractionButton = uibutton(app.LeftPanel, 'push');
            app.ClusterextractionButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterextractionButtonPushed, true);
            app.ClusterextractionButton.Visible = 'off';
            app.ClusterextractionButton.Text = 'Cluster extraction';
            app.LoadCNTEEGButton = uibutton(app.LeftPanel, 'push');
            app.LoadCNTEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCNTEEGButtonPushed, true);
            app.LoadCNTEEGButton.Text = 'Load CNT EEG';
            app.ClusternrEditFieldLabel = uilabel(app.LeftPanel);
            app.ClusternrEditFieldLabel.Visible = 'off';
            app.ClusternrEditFieldLabel.Text = 'Cluster nr';
            app.ClusternrEditField = uieditfield(app.LeftPanel, 'text');
            app.ClusternrEditField.Visible = 'off';
            app.HRFOPTIMIZATIONTOOLBOXLabel = uilabel(app.LeftPanel);
            app.HRFOPTIMIZATIONTOOLBOXLabel.Text = 'HRF OPTIMIZATION TOOLBOX';
            app.HOTLabel = uilabel(app.LeftPanel);
            app.HOTLabel.Text = 'HOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Fitted BOLD response with balloon HRF')
            xlabel(app.UIAxes_2, 'time[s]')
            ylabel(app.UIAxes_2, 'BOLD [a.u.]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Fitted BOLD response with glover HRF')
            xlabel(app.UIAxes_4, 'time[s]')
            ylabel(app.UIAxes_4, 'BOLD [a.u.]')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Fitted BOLD response with gamma HRF')
            xlabel(app.UIAxes_3, 'time[s]')
            ylabel(app.UIAxes_3, 'BOLD [a.u.]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Fitted BOLD response with canonical HRF')
            xlabel(app.UIAxes, 'time[s]')
            ylabel(app.UIAxes, 'BOLD [a.u.]')
            zlabel(app.UIAxes, 'Z')
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BETAEditFieldLabel = uilabel(app.RightPanel);
            app.BETAEditFieldLabel.Text = 'BETA';
            app.BETAEditField = uieditfield(app.RightPanel, 'text');
            app.MSEEditFieldLabel = uilabel(app.RightPanel);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.RightPanel, 'text');
            app.MSEEditField_2Label = uilabel(app.RightPanel);
            app.MSEEditField_2Label.Text = 'MSE';
            app.MSEEditField_2 = uieditfield(app.RightPanel, 'text');
            app.BETAEditField_4Label = uilabel(app.RightPanel);
            app.BETAEditField_4Label.Text = 'BETA';
            app.BETAEditField_2 = uieditfield(app.RightPanel, 'text');
            app.MSEEditField_3Label = uilabel(app.RightPanel);
            app.MSEEditField_3Label.Text = 'MSE';
            app.MSEEditField_3 = uieditfield(app.RightPanel, 'text');
            app.BETAEditField_6Label = uilabel(app.RightPanel);
            app.BETAEditField_6Label.Text = 'BETA';
            app.BETAEditField_3 = uieditfield(app.RightPanel, 'text');
            app.MSEEditField_4Label = uilabel(app.RightPanel);
            app.MSEEditField_4Label.Text = 'MSE';
            app.MSEEditField_4 = uieditfield(app.RightPanel, 'text');
            app.BETAEditField_8Label = uilabel(app.RightPanel);
            app.BETAEditField_8Label.Text = 'BETA';
            app.BETAEditField_4 = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HRF_OPTIM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end