classdef EEG2SPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEG2SPMToolboxUIFigure        matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TriggercodeEditField          matlab.ui.control.NumericEditField
        TriggercodeEditFieldLabel     matlab.ui.control.Label
        EventendcodeEditField         matlab.ui.control.NumericEditField
        EventendcodeEditFieldLabel    matlab.ui.control.Label
        EventonsetcodeEditField       matlab.ui.control.NumericEditField
        EventonsetcodeEditFieldLabel  matlab.ui.control.Label
        NrofsessionsEditField         matlab.ui.control.NumericEditField
        NrofsessionsEditFieldLabel    matlab.ui.control.Label
        EEG2SPMLabel                  matlab.ui.control.Label
        LOADEEGButton                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        SPManalysisLabel              matlab.ui.control.Label
        HRFsettingsLabel              matlab.ui.control.Label
        DefaultButton                 matlab.ui.control.Button
        ImportparametersButton        matlab.ui.control.Button
        P5EditField_2Label            matlab.ui.control.Label
        P4EditField_2Label            matlab.ui.control.Label
        P3EditField_2Label            matlab.ui.control.Label
        P2EditField_3Label            matlab.ui.control.Label
        P1EditField_glo               matlab.ui.control.NumericEditField
        P1EditField_3Label            matlab.ui.control.Label
        P7EditFieldLabel              matlab.ui.control.Label
        P2EditField_glo               matlab.ui.control.NumericEditField
        P3EditField_glo               matlab.ui.control.NumericEditField
        P4EditField_glo               matlab.ui.control.NumericEditField
        P5EditField_glo               matlab.ui.control.NumericEditField
        P1EditField_gam               matlab.ui.control.NumericEditField
        P1EditField_2Label            matlab.ui.control.Label
        P2EditField_2Label            matlab.ui.control.Label
        P2EditField_gam               matlab.ui.control.NumericEditField
        GloverCheckBox                matlab.ui.control.CheckBox
        GammaCheckBox                 matlab.ui.control.CheckBox
        CanonicalCheckBox             matlab.ui.control.CheckBox
        P7EditField                   matlab.ui.control.NumericEditField
        P6EditField                   matlab.ui.control.NumericEditField
        P6EditFieldLabel              matlab.ui.control.Label
        P5EditField                   matlab.ui.control.NumericEditField
        P5EditFieldLabel              matlab.ui.control.Label
        P4EditField                   matlab.ui.control.NumericEditField
        P4EditFieldLabel              matlab.ui.control.Label
        P3EditField                   matlab.ui.control.NumericEditField
        P3EditFieldLabel              matlab.ui.control.Label
        P2EditField                   matlab.ui.control.NumericEditField
        P2EditFieldLabel              matlab.ui.control.Label
        P1EditField                   matlab.ui.control.NumericEditField
        P1EditFieldLabel              matlab.ui.control.Label
        fMRIrepetitiontimeEditField   matlab.ui.control.NumericEditField
        fMRIrepetitiontimeEditFieldLabel  matlab.ui.control.Label
        DynamicanalysisCheckBox       matlab.ui.control.CheckBox
        ExportCSVCheckBox             matlab.ui.control.CheckBox
        ExportNiftimasksCheckBox      matlab.ui.control.CheckBox
        SerialcorrAR1CheckBox         matlab.ui.control.CheckBox
        CorrectionDropDown            matlab.ui.control.DropDown
        CorrectionDropDownLabel       matlab.ui.control.Label
        PvalueEditField               matlab.ui.control.NumericEditField
        PvalueEditFieldLabel          matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        EF % Description
        nros
        xpath
        codestat
        P_can
        P_gam
        P_glo
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';

            app.P_can=[6 16 1 1 6 0 32];
            app.P_gam=[8.6 0.547];
            app.P_glo=[6 12 0.9 0.9 0.35];
        end

        % Button pushed function: LOADEEGButton
        function LOADEEGButtonPushed(app, event)

            % Loading EEG data, extracting information about triggers and events, preprocessing

            if ~isempty(which('ft_defaults'))

                ft_defaults
                app.EF.EEG=struct([]);

                try

                    for i=1:app.NrofsessionsEditField.Value

                        uiwait(msgbox('SELECT EEG data','Select data'));  %select eeg data
                        [name, folder] = uigetfile('*.*');
                        app.EF.EEG(i).path= fullfile(folder, name);
                        clear name
                        cd (folder)
                    end

                    for i=1:app.NrofsessionsEditField.Value

                        %define config informations for ft processing, process the data
                        app.EF.EEG(i).cfg           = [];
                        app.EF.EEG(i).cfg.dataset    = app.EF.EEG(i).path;
                        app.EF.EEG(i).cfg.continuous = 'yes';
                        app.EF.EEG(i).cfg.channel    = { 'FP1' 'FP2' 'F7' 'F8' 'T7' 'T8' 'C5' 'C6' 'P5' 'P6' };
                        app.EF.EEG(i).data = ft_preprocessing(app.EF.EEG(i).cfg);
                        %get events
                        event=ft_read_event(app.EF.EEG(i).path);
                        idx = ismember({event.type},'trigger');
                        event=event(idx);
                        event = struct2table(event); % convert the struct array to a table
                        app.EF.EEG(i).event.trigger = event(event.value==app.TriggercodeEditField.Value,2);
                        app.EF.EEG(i).event.start = event(event.value==app.EventonsetcodeEditField.Value,2);
                        app.EF.EEG(i).event.end = event(event.value==app.EventendcodeEditField.Value,2);

                        if ~isempty(app.EF.EEG(i).event.trigger) && ~isempty(app.EF.EEG(i).event.start) && ~isempty(app.EF.EEG(i).event.end)
                            app.EF.EEG(i).event.startdouble=(table2array(app.EF.EEG(i).event.start)-app.EF.EEG(i).event.trigger.sample(1))/1000;
                            app.EF.EEG(i).event.enddouble=(table2array(app.EF.EEG(i).event.end)-app.EF.EEG(i).event.trigger.sample(1))/1000;
                            app.EF.EEG(i).event.dur=app.EF.EEG(i).event.enddouble-app.EF.EEG(i).event.startdouble;
                        end

                        clear event idx
                    end

                    clc
                    figure(app.EEG2SPMToolboxUIFigure)

                    app.codestat=0;
                    if isempty(app.EF.EEG(i).event.trigger)
                        app.codestat = 1;
                        errordlg('No trigger codes found', 'BEWARE!');
                    end
                    if isempty(app.EF.EEG(i).event.start)
                        app.codestat = 1;
                        errordlg('No event onset codes found', 'BEWARE!');
                    end
                    if isempty(app.EF.EEG(i).event.end)
                        app.codestat = 1;
                        errordlg('No event end codes found', 'BEWARE!');
                    end
                    if app.codestat==0
                        uiwait(msgbox('EEG data loaded succesfully','WELL DONE!'));
                    end

                catch
                    figure(app.EEG2SPMToolboxUIFigure)
                end

            else
                errordlg('Make sure you have added the FieldTrip toolbox to your Matlab path','BEWARE!');
            end

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            % Loading fMRI data (sr/swr recommended) and rp regressor file;
            % run fMRI analysis

            if isprop(app, 'EF') && ~isempty(app.EF) && app.codestat==0

                try
                    for i=1:app.NrofsessionsEditField.Value
                        uiwait(msgbox('SELECT fmri data and movement regressor','Select data'));
                        files = cellstr(spm_select(Inf,'image'));
                        for j=1:length(files)
                            files{j,1}=strcat(files{j,1},',1');
                        end
                        reg = cellstr(spm_select(1,'^rp_.*\.txt$'));
                        app.EF.fMRI(i).data=files;
                        app.EF.fMRI(i).rp=reg;
                        clear files reg
                    end


                    %
                    % Dynamic analysis, runs only for canonical model
                    %

                    if app.DynamicanalysisCheckBox.Value == 1
                        uiwait(msgbox('Choose directory for SPM outputs','Choose dir'));
                        app.xpath=uigetdir('C:\','Choose directory');


                        for j=1:1:21

                            cd (app.xpath)
                            mkdir(num2str(j-11));

                            spm('defaults','fmri');
                            spm_jobman('initcfg');


                            matlabbatch{1}.spm.stats.fmri_spec.dir = {fullfile(app.xpath,num2str(j-11))};
                            matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                            matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                            for i=1:app.NrofsessionsEditField.Value

                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble+(j-11);
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = 0.01;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                            end

                            matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                            matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                            matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                            matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                            matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                            if app.SerialcorrAR1CheckBox.Value==1
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                            else
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                            end

                            matlabbatch{2}.spm.stats.fmri_est.spmmat =  cellstr(fullfile(app.xpath,num2str(j-11), 'SPM.mat'));
                            matlabbatch{2}.spm.stats.fmri_est.write_residuals = 0;
                            matlabbatch{2}.spm.stats.fmri_est.method.Classical = 1;

                            %%%%

                            matlabbatch{3}.spm.stats.con.spmmat(1) = cfg_dep('Model estimation: SPM.mat File', substruct('.','val', '{}',{2}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));

                            if app.NrofsessionsEditField.Value == 1
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = 1;
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = -1;
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';

                            elseif app.NrofsessionsEditField.Value == 2

                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = [1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [-1 0 0 0 0 0 0 -1];
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';

                            elseif app.NrofsessionsEditField.Value == 3

                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [-1 0 0 0 0 0 0 -1 0 0 0 0 0 0 -1];
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                            end


                            matlabbatch{3}.spm.stats.con.delete = 0;

                            matlabbatch{4}.spm.stats.results.spmmat(1) = {fullfile(app.xpath,num2str(j-11), 'SPM.mat')};
                            matlabbatch{4}.spm.stats.results.conspec(1).titlestr = '';
                            matlabbatch{4}.spm.stats.results.conspec(1).contrasts = Inf;

                            if strcmpi('FWE',app.DataextractionDropDown.Value)
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                            else
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'none';
                            end

                            matlabbatch{4}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                            matlabbatch{4}.spm.stats.results.conspec(1).extent = 0;
                            matlabbatch{4}.spm.stats.results.conspec(1).conjunction = 1;
                            matlabbatch{4}.spm.stats.results.conspec(1).mask.none = 1;
                            matlabbatch{4}.spm.stats.results.units = 1;
                            matlabbatch{4}.spm.stats.results.export{1}.csv = true;
                            matlabbatch{4}.spm.stats.results.export{2}.xls = true;
                            matlabbatch{4}.spm.stats.results.export{3}.ps = true;
                            matlabbatch{4}.spm.stats.results.export{4}.jpg = true;
                            matlabbatch{4}.spm.stats.results.export{5}.nary.basename = 'activationcluster';


                            %


                            spm_jobman('run',matlabbatch);
                            clear matlabbatch
                        end


                    else


                        uiwait(msgbox('Choose directory for SPM outputs','Choose dir'));
                        app.xpath=uigetdir('C:\','Choose directory');
                        cd (app.xpath)


                        %
                        % Run analysis for canonical model
                        %

                        if app.CanonicalCheckBox.Value==1

                            mkdir spmreg

                            spm('defaults','fmri');
                            spm_jobman('initcfg');

                            global defaults
                            defaults.stats.fmri.hrf=[app.P_can];

                            matlabbatch{1}.spm.stats.fmri_spec.dir = cellstr(fullfile(app.xpath,'spmreg'));
                            matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                            matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                            for i=1:app.NrofsessionsEditField.Value

                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = app.EF.EEG(i).event.dur;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                            end

                            matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                            matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                            matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                            matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                            matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                            if app.SerialcorrAR1CheckBox.Value==1
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                            else
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                            end

                            matlabbatch{2}.spm.stats.fmri_est.spmmat =  cellstr(strcat(app.xpath,'/spmreg/', 'SPM.mat'));
                            matlabbatch{2}.spm.stats.fmri_est.write_residuals = 0;
                            matlabbatch{2}.spm.stats.fmri_est.method.Classical = 1;

                            %%%%

                            matlabbatch{3}.spm.stats.con.spmmat(1) = cfg_dep('Model estimation: SPM.mat File', substruct('.','val', '{}',{2}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));

                            matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP1';
                            matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = 1;
                            matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';

                            if app.NrofsessionsEditField.Value == 2
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.weights = [1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.sessrep = 'none';


                            elseif app.NrofsessionsEditField.Value == 3
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.name = 'EP3';
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.weights = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{3}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{4}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{4}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{4}.tcon.sessrep = 'none';
                            end


                            matlabbatch{3}.spm.stats.con.delete = 0;

                            matlabbatch{4}.spm.stats.results.spmmat(1) = cfg_dep('Contrast Manager: SPM.mat File', substruct('.','val', '{}',{3}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));
                            matlabbatch{4}.spm.stats.results.conspec(1).titlestr = '';
                            matlabbatch{4}.spm.stats.results.conspec(1).contrasts = Inf;

                            if strcmpi('FWE',app.CorrectionDropDown.Value)
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                            else
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'none';
                            end

                            matlabbatch{4}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                            matlabbatch{4}.spm.stats.results.conspec(1).thresh = 0.05;
                            matlabbatch{4}.spm.stats.results.conspec(1).extent = 0;
                            matlabbatch{4}.spm.stats.results.conspec(1).conjunction = 1;
                            matlabbatch{4}.spm.stats.results.conspec(1).mask.none = 1;
                            matlabbatch{4}.spm.stats.results.units = 1;

                            if app.ExportCSVCheckBox.Value == 1
                                matlabbatch{4}.spm.stats.results.export{1}.csv = true;
                                matlabbatch{4}.spm.stats.results.export{2}.xls = true;
                                matlabbatch{4}.spm.stats.results.export{3}.ps = true;
                                matlabbatch{4}.spm.stats.results.export{4}.jpg = true;
                                if app.ExportNiftimasksCheckBox.Value == 1
                                    matlabbatch{4}.spm.stats.results.export{5}.nary.basename = 'cluster';
                                end
                            end

                            if app.ExportCSVCheckBox.Value == 0 && app.ExportNiftimasksCheckBox.Value == 1
                                matlabbatch{4}.spm.stats.results.export{1}.nary.basename = 'cluster';
                            end

                            %


                            spm_jobman('run',matlabbatch);

                            clear matlabbatch
                        end

                        %
                        % Run analysis for gamma model
                        %

                        if app.GammaCheckBox.Value==1
                            
                            cd (app.xpath)
                            mkdir spmgamma

                            spm('defaults','fmri');
                            spm_jobman('initcfg');

                            matlabbatch{1}.spm.stats.fmri_spec.dir = cellstr(fullfile(app.xpath,'spmgamma'));
                            matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                            matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                            for i=1:app.NrofsessionsEditField.Value
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = app.EF.EEG(i).event.dur;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                            end

                            matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                            matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                            matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                            matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                            matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                            if app.SerialcorrAR1CheckBox.Value==1
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                            else
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                            end
                             spm_jobman('run',matlabbatch);

                            %%%%
                            load(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'))

                            b=app.P_gam(1);
                            c=app.P_gam(2);
                            t=linspace(0,32,205)';
                            hrf_gamma=((t/b*c).^b) .* (exp(1).^((b-t/c))); %gamma

                            for i=1:app.NrofsessionsEditField.Value
                                U=SPM.Sess(i).U.u;
                                U=full(U);
                                U=U(33:end,:);
                                Xr=filter(hrf_gamma,1,U);
                                Xr=downsample(Xr,16);
                                Xr=Xr/max(Xr);
                                SPM.xX.X((i-1)*SPM.nscan+1:i*SPM.nscan,(i-1)*7+1)= Xr;
                                clear U Xr
                            end
                            clear hrf_gamma t b c

                            cd (matlabbatch{1}.spm.stats.fmri_spec.dir{1,1})
                            save('SPM.mat','SPM')
                            clear SPM
                            %%%%

                            spm('defaults','fmri');
                            spm_jobman('initcfg');

                            matlabbatch1{1}.spm.stats.fmri_est.spmmat =   cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));
                            matlabbatch1{1}.spm.stats.fmri_est.write_residuals = 0;
                            matlabbatch1{1}.spm.stats.fmri_est.method.Classical = 1;

                            %%%%

                            matlabbatch1{2}.spm.stats.con.spmmat(1) = cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));

                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.name = 'EP1';
                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.weights = 1;
                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.sessrep = 'none';

                            if app.NrofsessionsEditField.Value == 2
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.name = 'EP';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.weights = [1 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.sessrep = 'none';


                            elseif app.NrofsessionsEditField.Value == 3
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.name = 'EP3';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.weights = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.sessrep = 'none';
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.name = 'EP';
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.sessrep = 'none';
                            end


                            matlabbatch1{2}.spm.stats.con.delete = 0;

                            matlabbatch1{3}.spm.stats.results.spmmat(1) = cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));
                            matlabbatch1{3}.spm.stats.results.conspec(1).titlestr = '';
                            matlabbatch1{3}.spm.stats.results.conspec(1).contrasts = Inf;

                            if strcmpi('FWE',app.CorrectionDropDown.Value)
                                matlabbatch1{3}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                            else
                                matlabbatch1{3}.spm.stats.results.conspec(1).threshdesc = 'none';
                            end

                            matlabbatch1{3}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                            matlabbatch1{3}.spm.stats.results.conspec(1).thresh = 0.05;
                            matlabbatch1{3}.spm.stats.results.conspec(1).extent = 0;
                            matlabbatch1{3}.spm.stats.results.conspec(1).conjunction = 1;
                            matlabbatch1{3}.spm.stats.results.conspec(1).mask.none = 1;
                            matlabbatch1{3}.spm.stats.results.units = 1;

                            if app.ExportCSVCheckBox.Value == 1
                                matlabbatch1{3}.spm.stats.results.export{1}.csv = true;
                                matlabbatch1{3}.spm.stats.results.export{2}.xls = true;
                                matlabbatch1{3}.spm.stats.results.export{3}.ps = true;
                                matlabbatch1{3}.spm.stats.results.export{4}.jpg = true;
                                if app.ExportNiftimasksCheckBox.Value == 1
                                    matlabbatch1{3}.spm.stats.results.export{5}.nary.basename = 'cluster';
                                end
                            end

                            if app.ExportCSVCheckBox.Value == 0 && app.ExportNiftimasksCheckBox.Value == 1
                                matlabbatch1{3}.spm.stats.results.export{1}.nary.basename = 'cluster';
                            end
                            
                            spm_jobman('run',matlabbatch1);
                            clear matlabbatch matlabbatch1
                        end


                        %
                        % Run analysis for glover model
                        %

                        if app.GloverCheckBox.Value==1

                            cd (app.xpath)
                            mkdir spmglover

                            spm('defaults','fmri');
                            spm_jobman('initcfg');

                            matlabbatch{1}.spm.stats.fmri_spec.dir = cellstr(fullfile(app.xpath,'spmglover'));
                            matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                            matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                            for i=1:app.NrofsessionsEditField.Value

                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = app.EF.EEG(i).event.dur;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                            end

                            matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                            matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                            matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                            matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                            matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                            if app.SerialcorrAR1CheckBox.Value==1
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                            else
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                            end
                            spm_jobman('run',matlabbatch);

                            %%%%
                            load(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'))

                            a1 = app.P_glo(1);
                            a2 = app.P_glo(2);
                            b1 = app.P_glo(3);
                            b2 = app.P_glo(4);
                            c = app.P_glo(5);
                            t=linspace(0,32,205)';
                            d1=a1*b1;
                            d2=a2*b2;
                            hrf_glover=((t/d1).^a1).*(exp(1).^-((t-d1)/b1)) - c.*((t/d2).^a2).*(exp(1).^-((t-d2)/b2));

                            for i=1:app.NrofsessionsEditField.Value
                                U=SPM.Sess(i).U.u;
                                U=full(U);
                                U=U(33:end,:);
                                Xr=filter(hrf_glover,1,U);
                                Xr=downsample(Xr,16);
                                Xr=Xr/max(Xr);
                                SPM.xX.X((i-1)*SPM.nscan+1:i*SPM.nscan,(i-1)*7+1)= Xr;
                                clear U Xr
                            end

                            clear a1 a2 b1 b2 c t d1 d2 hrf_glover
                            cd (matlabbatch{1}.spm.stats.fmri_spec.dir{1,1})
                            save('SPM.mat','SPM')
                            clear SPM
                            %%%%

                            spm('defaults','fmri');
                            spm_jobman('initcfg');

                            matlabbatch1{1}.spm.stats.fmri_est.spmmat =   cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));
                            matlabbatch1{1}.spm.stats.fmri_est.write_residuals = 0;
                            matlabbatch1{1}.spm.stats.fmri_est.method.Classical = 1;

                            %%%%

                            matlabbatch1{2}.spm.stats.con.spmmat(1) = cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));
                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.name = 'EP1';
                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.weights = 1;
                            matlabbatch1{2}.spm.stats.con.consess{1}.tcon.sessrep = 'none';

                            if app.NrofsessionsEditField.Value == 2
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.name = 'EP';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.weights = [1 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.sessrep = 'none';


                            elseif app.NrofsessionsEditField.Value == 3
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                                matlabbatch1{2}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.name = 'EP3';
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.weights = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{3}.tcon.sessrep = 'none';
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.name = 'EP';
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                                matlabbatch1{2}.spm.stats.con.consess{4}.tcon.sessrep = 'none';
                            end


                            matlabbatch1{2}.spm.stats.con.delete = 0;

                            matlabbatch1{3}.spm.stats.results.spmmat(1) = cellstr(fullfile(matlabbatch{1}.spm.stats.fmri_spec.dir{1,1},'SPM.mat'));
                            matlabbatch1{3}.spm.stats.results.conspec(1).titlestr = '';
                            matlabbatch1{3}.spm.stats.results.conspec(1).contrasts = Inf;

                            if strcmpi('FWE',app.CorrectionDropDown.Value)
                                matlabbatch1{3}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                            else
                                matlabbatch1{3}.spm.stats.results.conspec(1).threshdesc = 'none';
                            end

                            matlabbatch1{3}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                            matlabbatch1{3}.spm.stats.results.conspec(1).thresh = 0.05;
                            matlabbatch1{3}.spm.stats.results.conspec(1).extent = 0;
                            matlabbatch1{3}.spm.stats.results.conspec(1).conjunction = 1;
                            matlabbatch1{3}.spm.stats.results.conspec(1).mask.none = 1;
                            matlabbatch1{3}.spm.stats.results.units = 1;

                            if app.ExportCSVCheckBox.Value == 1
                                matlabbatch1{3}.spm.stats.results.export{1}.csv = true;
                                matlabbatch1{3}.spm.stats.results.export{2}.xls = true;
                                matlabbatch1{3}.spm.stats.results.export{3}.ps = true;
                                matlabbatch1{3}.spm.stats.results.export{4}.jpg = true;
                                if app.ExportNiftimasksCheckBox.Value == 1
                                    matlabbatch1{3}.spm.stats.results.export{5}.nary.basename = 'cluster';
                                end
                            end

                            if app.ExportCSVCheckBox.Value == 0 && app.ExportNiftimasksCheckBox.Value == 1
                                matlabbatch1{3}.spm.stats.results.export{1}.nary.basename = 'cluster';
                            end
                            
                            spm_jobman('run',matlabbatch1);
                            clear matlabbatch matlabbatch1
                        end

                    end

                catch
                    figure(app.EEG2SPMToolboxUIFigure)
                end

            else
                errordlg('Load proper EEG data first.','BEWARE!');
            end


        end

        % Value changed function: CanonicalCheckBox
        function CanonicalCheckBoxValueChanged(app, event)

            if app.CanonicalCheckBox.Value == 1
                app.P1EditField.Editable='on';
                app.P2EditField.Editable='on';
                app.P3EditField.Editable='on';
                app.P4EditField.Editable='on';
                app.P5EditField.Editable='on';
                app.P6EditField.Editable='on';
                app.P7EditField.Editable='on';
            else
                app.P1EditField.Editable='off';
                app.P2EditField.Editable='off';
                app.P3EditField.Editable='off';
                app.P4EditField.Editable='off';
                app.P5EditField.Editable='off';
                app.P6EditField.Editable='off';
                app.P7EditField.Editable='off';
            end
        end

        % Value changed function: GammaCheckBox
        function GammaCheckBoxValueChanged(app, event)

            if app.GammaCheckBox.Value == 1
                app.P1EditField_gam.Editable='on';
                app.P2EditField_gam.Editable='on';
            else
                app.P1EditField_gam.Editable='off';
                app.P2EditField_gam.Editable='off';
            end
        end

        % Value changed function: GloverCheckBox
        function GloverCheckBoxValueChanged(app, event)

            if app.GloverCheckBox.Value == 1
                app.P1EditField_glo.Editable='on';
                app.P2EditField_glo.Editable='on';
                app.P3EditField_glo.Editable='on';
                app.P4EditField_glo.Editable='on';
                app.P5EditField_glo.Editable='on';

            else
                app.P1EditField_glo.Editable='off';
                app.P2EditField_glo.Editable='off';
                app.P3EditField_glo.Editable='off';
                app.P4EditField_glo.Editable='off';
                app.P5EditField_glo.Editable='off';
            end

        end

        % Button pushed function: ImportparametersButton
        function ImportparametersButtonPushed(app, event)
            % Import HRF parameters from file

            try

                [file, path] = uigetfile('*.mat', 'Select Parameters File');
                fullFilePath = fullfile(path, file);
                param = load(fullFilePath);

                if isfield(param, 'parameters') && isfield(param.parameters, 'can') && isfield(param.parameters, 'gam') && isfield(param.parameters, 'glo')

                    for i=1:7
                        app.P_can(i) = param.parameters.can(i);
                    end

                    app.P1EditField.Value=app.P_can(1);
                    app.P2EditField.Value=app.P_can(2);
                    app.P3EditField.Value=app.P_can(3);
                    app.P4EditField.Value=app.P_can(4);
                    app.P5EditField.Value=app.P_can(5);
                    app.P6EditField.Value=app.P_can(6);
                    app.P7EditField.Value=app.P_can(7);

                    for i=1:2
                        app.P_gam(i) = param.parameters.gam(i);
                    end

                    app.P1EditField_gam.Value=app.P_gam(1);
                    app.P2EditField_gam.Value=app.P_gam(2);

                    for i=1:5
                        app.P_glo(i) = param.parameters.glo(i);
                    end

                    app.P1EditField_glo.Value=app.P_glo(1);
                    app.P2EditField_glo.Value=app.P_glo(2);
                    app.P3EditField_glo.Value=app.P_glo(3);
                    app.P4EditField_glo.Value=app.P_glo(4);
                    app.P5EditField_glo.Value=app.P_glo(5);

                    figure(app.EEG2SPMToolboxUIFigure)
                    uiwait(msgbox('HRF parameters loaded succesfully','WELL DONE!'));
                else
                    figure(app.EEG2SPMToolboxUIFigure)
                    errordlg('Invalid parameter file', 'BEWARE!');
                end

            catch
                figure(app.EEG2SPMToolboxUIFigure)
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % Reset HRF to default values

            app.P1EditField.Value=6;
            app.P_can(1)=6;
            app.P2EditField.Value=16;
            app.P_can(2)=16;
            app.P3EditField.Value=1;
            app.P_can(3)=1;
            app.P4EditField.Value=1;
            app.P_can(4)=1;
            app.P5EditField.Value=6;
            app.P_can(5)=6;
            app.P6EditField.Value=0;
            app.P_can(6)=0;
            app.P7EditField.Value=32;
            app.P_can(7)=32;

            app.P1EditField_gam.Value=8.6;
            app.P_gam(1)=8.6;
            app.P2EditField_gam.Value=0.54;
            app.P_gam(2)=0.54;

            app.P1EditField_glo.Value=6;
            app.P_glo(1)=6;
            app.P2EditField_glo.Value=12;
            app.P_glo(2)=12;
            app.P3EditField_glo.Value=0.9;
            app.P_glo(3)=0.9;
            app.P4EditField_glo.Value=0.9;
            app.P_glo(4)=0.9;
            app.P5EditField_glo.Value=0.35;
            app.P_glo(5)=0.35;

        end

        % Value changed function: DynamicanalysisCheckBox
        function DynamicanalysisCheckBoxValueChanged(app, event)

            if app.DynamicanalysisCheckBox.Value == 1
                app.GammaCheckBox.Value=0;
                app.P1EditField_gam.Editable='off';
                app.P2EditField_gam.Editable='off';
                app.GloverCheckBox.Value=0;
                app.P1EditField_glo.Editable='off';
                app.P2EditField_glo.Editable='off';
                app.P3EditField_glo.Editable='off';
                app.P4EditField_glo.Editable='off';
                app.P5EditField_glo.Editable='off';

            end

        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)
            app.P_can(1) = app.P1EditField.Value;
        end

        % Value changed function: P2EditField
        function P2EditFieldValueChanged(app, event)
          app.P_can(2) = app.P2EditField.Value;
        end

        % Value changed function: P3EditField
        function P3EditFieldValueChanged(app, event)
           app.P_can(3) = app.P3EditField.Value;
        end

        % Value changed function: P4EditField
        function P4EditFieldValueChanged(app, event)
             app.P_can(4) = app.P4EditField.Value;
        end

        % Value changed function: P5EditField
        function P5EditFieldValueChanged(app, event)
            app.P_can(5) = app.P5EditField.Value;
        end

        % Value changed function: P6EditField
        function P6EditFieldValueChanged(app, event)
            app.P_can(6) = app.P6EditField.Value;
        end

        % Value changed function: P7EditField
        function P7EditFieldValueChanged(app, event)
            app.P_can(7) = app.P7EditField.Value;
        end

        % Value changed function: P1EditField_gam
        function P1EditField_gamValueChanged(app, event)
            app.P_gam(1) = app.P1EditField_gam.Value;
        end

        % Value changed function: P2EditField_gam
        function P2EditField_gamValueChanged(app, event)
            app.P_gam(2) = app.P2EditField_gam.Value;
        end

        % Value changed function: P1EditField_glo
        function P1EditField_gloValueChanged(app, event)
           app.P_glo(1) = app.P1EditField_glo.Value; 
        end

        % Value changed function: P2EditField_glo
        function P2EditField_gloValueChanged(app, event)
            app.P_glo(2) = app.P2EditField_glo.Value;
        end

        % Value changed function: P3EditField_glo
        function P3EditField_gloValueChanged(app, event)
            app.P_glo(3) = app.P3EditField_glo.Value;
        end

        % Value changed function: P4EditField_glo
        function P4EditField_gloValueChanged(app, event)
            app.P_glo(4) = app.P4EditField_glo.Value; 
        end

        % Value changed function: P5EditField_glo
        function P5EditField_gloValueChanged(app, event)
            app.P_glo(5) = app.P5EditField_glo.Value; 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEG2SPMToolboxUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {464, 464};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {324, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEG2SPMToolboxUIFigure = uifigure('Visible', 'off');
            app.EEG2SPMToolboxUIFigure.Name = 'EEG2SPM Toolbox';
            app.EEG2SPMToolboxUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEG2SPMToolboxUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LOADEEGButton = uibutton(app.LeftPanel, 'push');
            app.LOADEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LOADEEGButtonPushed, true);
            app.LOADEEGButton.Text = 'LOAD EEG';
            app.EEG2SPMLabel = uilabel(app.LeftPanel);
            app.EEG2SPMLabel.Text = 'EEG2SPM';
            app.NrofsessionsEditFieldLabel = uilabel(app.LeftPanel);
            app.NrofsessionsEditFieldLabel.Text = 'Nr of sessions';
            app.NrofsessionsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NrofsessionsEditField.Limits = [1 3];
            app.NrofsessionsEditField.Value = 1;
            app.EventonsetcodeEditFieldLabel = uilabel(app.LeftPanel);
            app.EventonsetcodeEditFieldLabel.Text = 'Event onset code';
            app.EventonsetcodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EventonsetcodeEditField.Value = 201;
            app.EventendcodeEditFieldLabel = uilabel(app.LeftPanel);
            app.EventendcodeEditFieldLabel.Text = 'Event end code';
            app.EventendcodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EventendcodeEditField.Value = 202;
            app.TriggercodeEditFieldLabel = uilabel(app.LeftPanel);
            app.TriggercodeEditFieldLabel.Text = 'Trigger code';
            app.TriggercodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RUNButton = uibutton(app.RightPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.PvalueEditFieldLabel = uilabel(app.RightPanel);
            app.PvalueEditFieldLabel.Text = 'P value';
            app.PvalueEditField = uieditfield(app.RightPanel, 'numeric');
            app.PvalueEditField.Value = 0.05;
            app.CorrectionDropDownLabel = uilabel(app.RightPanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.RightPanel);
            app.CorrectionDropDown.Items = {'FWE', 'uncorrected'};
            app.CorrectionDropDown.Value = 'FWE';
            app.SerialcorrAR1CheckBox = uicheckbox(app.RightPanel);
            app.SerialcorrAR1CheckBox.Text = 'Serial corr: AR(1)';
            app.ExportNiftimasksCheckBox = uicheckbox(app.RightPanel);
            app.ExportNiftimasksCheckBox.Text = 'Export: Nifti masks';
            app.ExportNiftimasksCheckBox.Value = true;
            app.ExportCSVCheckBox = uicheckbox(app.RightPanel);
            app.ExportCSVCheckBox.Text = 'Export: CSV';
            app.ExportCSVCheckBox.Value = true;
            app.DynamicanalysisCheckBox = uicheckbox(app.RightPanel);
            app.DynamicanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DynamicanalysisCheckBoxValueChanged, true);
            app.DynamicanalysisCheckBox.Text = 'Dynamic analysis';
            app.fMRIrepetitiontimeEditFieldLabel = uilabel(app.RightPanel);
            app.fMRIrepetitiontimeEditFieldLabel.Text = 'fMRI repetition time';
            app.fMRIrepetitiontimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.fMRIrepetitiontimeEditField.Value = 2.5;
            app.P1EditFieldLabel = uilabel(app.RightPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.RightPanel, 'numeric');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.P1EditField.Value = 6;
            app.P2EditFieldLabel = uilabel(app.RightPanel);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.RightPanel, 'numeric');
            app.P2EditField.ValueChangedFcn = createCallbackFcn(app, @P2EditFieldValueChanged, true);
            app.P2EditField.Value = 16;
            app.P3EditFieldLabel = uilabel(app.RightPanel);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.RightPanel, 'numeric');
            app.P3EditField.ValueChangedFcn = createCallbackFcn(app, @P3EditFieldValueChanged, true);
            app.P3EditField.Value = 1;
            app.P4EditFieldLabel = uilabel(app.RightPanel);
            app.P4EditFieldLabel.Text = 'P4';
            app.P4EditField = uieditfield(app.RightPanel, 'numeric');
            app.P4EditField.ValueChangedFcn = createCallbackFcn(app, @P4EditFieldValueChanged, true);
            app.P4EditField.Value = 1;
            app.P5EditFieldLabel = uilabel(app.RightPanel);
            app.P5EditFieldLabel.Text = 'P5';
            app.P5EditField = uieditfield(app.RightPanel, 'numeric');
            app.P5EditField.ValueChangedFcn = createCallbackFcn(app, @P5EditFieldValueChanged, true);
            app.P5EditField.Value = 6;
            app.P6EditFieldLabel = uilabel(app.RightPanel);
            app.P6EditFieldLabel.Text = 'P6';
            app.P6EditField = uieditfield(app.RightPanel, 'numeric');
            app.P6EditField.ValueChangedFcn = createCallbackFcn(app, @P6EditFieldValueChanged, true);
            app.P7EditField = uieditfield(app.RightPanel, 'numeric');
            app.P7EditField.ValueChangedFcn = createCallbackFcn(app, @P7EditFieldValueChanged, true);
            app.P7EditField.Value = 32;
            app.CanonicalCheckBox = uicheckbox(app.RightPanel);
            app.CanonicalCheckBox.ValueChangedFcn = createCallbackFcn(app, @CanonicalCheckBoxValueChanged, true);
            app.CanonicalCheckBox.Text = 'Canonical';
            app.CanonicalCheckBox.Value = true;
            app.GammaCheckBox = uicheckbox(app.RightPanel);
            app.GammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBoxValueChanged, true);
            app.GammaCheckBox.Text = 'Gamma';
            app.GloverCheckBox = uicheckbox(app.RightPanel);
            app.GloverCheckBox.ValueChangedFcn = createCallbackFcn(app, @GloverCheckBoxValueChanged, true);
            app.GloverCheckBox.Text = 'Glover';
            app.P2EditField_gam = uieditfield(app.RightPanel, 'numeric');
            app.P2EditField_gam.ValueChangedFcn = createCallbackFcn(app, @P2EditField_gamValueChanged, true);
            app.P2EditField_gam.Editable = 'off';
            app.P2EditField_gam.Value = 0.547;
            app.P2EditField_2Label = uilabel(app.RightPanel);
            app.P2EditField_2Label.Text = 'P2';
            app.P1EditField_2Label = uilabel(app.RightPanel);
            app.P1EditField_2Label.Text = 'P1';
            app.P1EditField_gam = uieditfield(app.RightPanel, 'numeric');
            app.P1EditField_gam.ValueChangedFcn = createCallbackFcn(app, @P1EditField_gamValueChanged, true);
            app.P1EditField_gam.Editable = 'off';
            app.P1EditField_gam.Value = 8.6;
            app.P5EditField_glo = uieditfield(app.RightPanel, 'numeric');
            app.P5EditField_glo.ValueChangedFcn = createCallbackFcn(app, @P5EditField_gloValueChanged, true);
            app.P5EditField_glo.Editable = 'off';
            app.P5EditField_glo.Value = 0.35;
            app.P4EditField_glo = uieditfield(app.RightPanel, 'numeric');
            app.P4EditField_glo.ValueChangedFcn = createCallbackFcn(app, @P4EditField_gloValueChanged, true);
            app.P4EditField_glo.Editable = 'off';
            app.P4EditField_glo.Value = 0.9;
            app.P3EditField_glo = uieditfield(app.RightPanel, 'numeric');
            app.P3EditField_glo.ValueChangedFcn = createCallbackFcn(app, @P3EditField_gloValueChanged, true);
            app.P3EditField_glo.Editable = 'off';
            app.P3EditField_glo.Value = 0.9;
            app.P2EditField_glo = uieditfield(app.RightPanel, 'numeric');
            app.P2EditField_glo.ValueChangedFcn = createCallbackFcn(app, @P2EditField_gloValueChanged, true);
            app.P2EditField_glo.Editable = 'off';
            app.P2EditField_glo.Value = 12;
            app.P7EditFieldLabel = uilabel(app.RightPanel);
            app.P7EditFieldLabel.Text = 'P7';
            app.P1EditField_3Label = uilabel(app.RightPanel);
            app.P1EditField_3Label.Text = 'P1';
            app.P1EditField_glo = uieditfield(app.RightPanel, 'numeric');
            app.P1EditField_glo.ValueChangedFcn = createCallbackFcn(app, @P1EditField_gloValueChanged, true);
            app.P1EditField_glo.Editable = 'off';
            app.P1EditField_glo.Value = 6;
            app.P2EditField_3Label = uilabel(app.RightPanel);
            app.P2EditField_3Label.Text = 'P2';
            app.P3EditField_2Label = uilabel(app.RightPanel);
            app.P3EditField_2Label.Text = 'P3';
            app.P4EditField_2Label = uilabel(app.RightPanel);
            app.P4EditField_2Label.Text = 'P4';
            app.P5EditField_2Label = uilabel(app.RightPanel);
            app.P5EditField_2Label.Text = 'P5';
            app.ImportparametersButton = uibutton(app.RightPanel, 'push');
            app.ImportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportparametersButtonPushed, true);
            app.ImportparametersButton.Text = 'Import parameters';
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.HRFsettingsLabel = uilabel(app.RightPanel);
            app.HRFsettingsLabel.Text = 'HRF settings';
            app.SPManalysisLabel = uilabel(app.RightPanel);
            app.SPManalysisLabel.Text = 'SPM analysis';
            % Show the figure after all components are created
            app.EEG2SPMToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG2SPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEG2SPMToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEG2SPMToolboxUIFigure)
        end
    end
end